classdef UploadApp < matlab.apps.AppBase
    %UPLOADAPP Data aggregation tool for porpoise detection data
    %   The app allows batch upload of C-POD and F-POD data (CP1/3 and
    %   FP1/3 files) as well as metadata input related to each deployment
    %   into the European Tracking Network data repository with further
    %   ingestion into EDITOinfra.
    %   
    %   If agreed to, the input files will be stored in the Marine Data
    %   Archive.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FileselectionTab                matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ResolutiontoextractButtonGroup  matlab.ui.container.ButtonGroup
        DPHButton                       matlab.ui.control.RadioButton
        DPMhourButton                   matlab.ui.control.RadioButton
        DPMButton                       matlab.ui.control.RadioButton
        DPSButton                       matlab.ui.control.RadioButton
        GridLayout4                     matlab.ui.container.GridLayout
        ConfirmselectionButton          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        FilesSelectedTextArea           matlab.ui.control.TextArea
        FilesSelectedTextAreaLabel      matlab.ui.control.Label
        ChoosefilestouploadButton       matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        MetadatainputTab                matlab.ui.container.Tab
        OptionalmetadatainputPanel      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        MandatorymetadatainputPanel     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        CurrentfileTextArea             matlab.ui.control.TextArea
        CurrentfileTextAreaLabel        matlab.ui.control.Label
        NextfileButton                  matlab.ui.control.Button
        DetectionclassifierButtonGroup  matlab.ui.container.ButtonGroup
        OtherPleasespecifyButton        matlab.ui.control.RadioButton
        HEL1Button                      matlab.ui.control.RadioButton
        KERNOButton                     matlab.ui.control.RadioButton
        ActivationdatetimeEditField     matlab.ui.control.EditField
        ActivationdatetimeEditFieldLabel  matlab.ui.control.Label
        DatavaliduntildatetimeEditField  matlab.ui.control.EditField
        DatavaliduntildatetimeEditFieldLabel  matlab.ui.control.Label
        RecoverydatetimeEditField       matlab.ui.control.EditField
        RecoverydatetimeEditFieldLabel  matlab.ui.control.Label
        DeploymentdatetimeEditField     matlab.ui.control.EditField
        DeploymentdatetimeEditFieldLabel  matlab.ui.control.Label
        ReceivernameEditField           matlab.ui.control.EditField
        ReceivernameEditFieldLabel      matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.EditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.EditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        StationnameEditField            matlab.ui.control.EditField
        StationnameEditFieldLabel       matlab.ui.control.Label
        ProjectnameEditField            matlab.ui.control.EditField
        ProjectnameEditFieldLabel       matlab.ui.control.Label
        MooringtypeButtonGroup          matlab.ui.container.ButtonGroup
        bottommooringButton             matlab.ui.control.RadioButton
        SurfacebuoyButton               matlab.ui.control.RadioButton
        UploadTab                       matlab.ui.container.Tab
        MessageTextArea                 matlab.ui.control.TextArea
        MessageTextAreaLabel            matlab.ui.control.Label
        UploadfilestodatabaseButton     matlab.ui.control.Button
    end

    %============================
    % Properties for storing data
    %============================
    properties (Access = private)
        SelectedFilePath % Path of the selected file
        conn             % Database connection
        files            % input file names
        filtFiles        % filtered filenames
        cp1files
        cp3files
        fp1files
        fp3files
        
        Resolution

        % Metadata input values:
        ProjectName  
        StationName 
        ReceiverName 
        Latitude  
        Longitude 
        MooringType 
        Deploy_datetime  
        Recover_datetime 
        Active_datetime 
        Valid_data_until_datetime 
        Classifier 

        MetadataTable     % table to hold metadata for each file selected
        CurrentFileIndex  % index to keep track of each file selected
    end

    methods (Access = private)
        %=====================================
        % Function to check the selected files
        %=====================================

        function checkFiles(app, files)
            % Supported file extensions
            supportedExtensions = {'.CP1', '.CP3', '.FP1', '.FP3'};

            % Initialize file pairs
            cp1Files = {};
            cp3Files = {};
            fp1Files = {};
            fp3Files = {};

            % Check each file
            for i = 1:length(files)
                [~, name, ext] = fileparts(files{i});

                % Check if the file extension is supported
                if ~ismember(upper(ext), supportedExtensions)
                    uialert(app.UIFigure, ...
                        sprintf('Wrong file type selected - type selected: %s. The formats supported are .CP1, .CP3, .FP1, or .FP3.', ext), ...
                        'File Type Error');
                    return;
                end

                % add the file names to correct array for validation later
                switch ext
                    case '.CP1'
                        cp1Files{end+1} = name;
                    case '.CP3'
                        cp3Files{end+1} = name;
                    case '.FP1'
                        fp1Files{end+1} = name;
                    case '.FP3'
                        fp3Files{end+1} = name;
                end
            end

            % check for missing files in a file pair
            missingFiles = {};
            missingFiles = [missingFiles, setdiff(cp1Files, cp3Files)];
            missingFiles = [missingFiles, setdiff(cp3Files, cp1Files)];
            missingFiles = [missingFiles, setdiff(fp1Files, fp3Files)];
            missingFiles = [missingFiles, setdiff(fp3Files, fp1Files)];
            
            isValid = isempty(missingFiles); % check if there are any missing files - 1 is no files missing, 0 is files are missing

            if ~isValid % if isValid is not empty give an alert
                uialert(app.UIFigure,['Missing corresponding file pairs: ', strjoin(missingFiles, ', '), '. Check that corresponding .CP1/.CP3 or .FP1/.FP3 files are selected.'],...
                    'Error');
                return;
            end

            % If all checks pass no message will display but confirm button
            % is enabled
            app.ConfirmselectionButton.Enable = 'on'; % enable confirm button

            % Save the file lists
            app.cp1files = cp1Files ;
            app.cp3files = cp3Files ;
            app.fp1files = fp1Files ;
            app.fp3files = fp3Files ;

        end
        
        
        %========================================================
        % Function to check all mandatory metadata has been input
        %========================================================

        function validateInputs(app)
            % OBS: if-statement does not include the mooring or classifier
            % re: input - will go ahead with default values if none is selected
            if ~isempty(app.ProjectName) && ~isempty(app.StationName) && ~isempty(app.ReceiverName) && ~isempty(app.Latitude) && ~isempty(app.Longitude) && ~isempty(app.Deploy_datetime) && ~isempty(app.Recover_datetime) && ~isempty(app.Active_datetime) && ~isempty(app.Valid_data_until_datetime)
                app.NextfileButton.Enable = 'on';
            else
                app.NextfileButton.Enable = 'off';
            end
        end
        
        
        %===================================================
        % Fucntion to validate latitude and longitude inputs
        %===================================================

        function [isValid, errorMsg] = validateCoordinates(app)
            latitude = str2double(app.LatitudeEditField.Value);
            longitude = str2double(app.LongitudeEditField.Value);
            isValidLat = ~isnan(latitude) && latitude >= -90 && latitude <= 90;
            isValidLon = ~isnan(longitude) && longitude >= -180 && longitude <= 180;
            errorMsg = '';

            if isValidLat
                app.LatitudeEditField.BackgroundColor = 'white';
            else
                app.LatitudeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, '- Invalid latitude.\n'];
            end

            if isValidLon
                app.LongitudeEditField.BackgroundColor = 'white';
            else
                app.LongitudeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, '- Invalid longitude.\n'];
            end

            isValid = isValidLat && isValidLon;
        end
        
        
        %============================================
        % Function to validate datetime relationships
        %============================================

        function [isValid, errorMsg] = validateDatetimeRelationships(app)
            errorMsg = '';
            isValid = true;

            try
                deployment = datetime(app.DeploymentdatetimeEditField.Value, 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
            catch
                app.DeploymentdatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Invalid deployment datetime format.\n'];
                isValid = false;
            end

            try
                recovery = datetime(app.RecoverydatetimeEditField.Value, 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
            catch
                app.RecoverydatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Invalid recovery datetime format.\n'];
                isValid = false;
            end

            try
                activation = datetime(app.ActivationdatetimeEditField.Value, 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
            catch
                app.ActivationdatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Invalid activation datetime format.\n'];
                isValid = false;
            end

            try
                validDataUntil = datetime(app.DatavaliduntildatetimeEditField.Value, 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
            catch
                app.DatavaliduntildatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Invalid valid data until datetime format.\n'];
                isValid = false;
            end

            if ~isValid
                return;
            end

            % Rule 1: validDataUntil, activation, and deployment are before recovery
            if validDataUntil > recovery
                app.DatavaliduntildatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Valid data until must be before recovery.\n'];
            end
            if activation > recovery
                app.ActivationdatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Activation must be before recovery.\n'];
            end
            if deployment > recovery
                app.DeploymentdatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Deployment must be before recovery.\n'];
            end

            % Rule 2: activation and validDataUntil are after deployment
            if activation < deployment
                app.ActivationdatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Activation must be after deployment.\n'];
            end
            if validDataUntil < deployment
                app.DatavaliduntildatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Valid data until must be after deployment.\n'];
            end

            % Rule 3: validDataUntil is after activation
            if validDataUntil < activation
                app.DatavaliduntildatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red
                errorMsg = [errorMsg, 'Valid data until must be after activation.\n'];
            end
            isValid = isempty(errorMsg);
        end

        %============================================
        % Function to update the Current File display
        %============================================
        function updateFileDisplay(app)
            % Get just the filename without extension
            [~, name, ext] = fileparts(app.filtFiles{app.CurrentFileIndex});
            app.CurrentfileTextArea.Value = name;
        end
        
        %====================================================
        % Function to display text in text area of Upload tab
        %====================================================
        % OBS: could be redundant
        function updateTextArea(app, files)
            app.FilesSelectedTextArea.Value = files ;
            numLines = numel(app.FilesSelectedTextArea.Value);
            app.FilesSelectedTextArea.Position(4) = 22 * numLines; % adjust height based on number of lines
        end

        %=======================
        % Process selected files
        %=======================
        % TO DO: set up function to process data depending on the POD type
        % function processFiles(app)
        %     % Get the list of uploaded files
        %     files = app.UploadedFiles; % Assuming this is a cell array of file names
        % 
        %     % Initialize containers for file pairs
        %     cpPairs = {};
        %     fpPairs = {};
        % 
        %     % Identify and group file pairs
        %     for i = 1:length(files)
        %         [~, name, ext] = fileparts(files{i});
        %         switch ext
        %             case '.CP1'
        %                 if ismember([name, '.CP3'], files)
        %                     cpPairs{end+1} = {files{i}, [name, '.CP3']};
        %                 end
        %             case '.FP1'
        %                 if ismember([name, '.FP3'], files)
        %                     fpPairs{end+1} = {files{i}, [name, '.FP3']};
        %                 end
        %         end
        %     end
        % 
        %     % Process CP pairs
        %     for i = 1:length(cpPairs)
        %         data = processCPPair(cpPairs{i}{1}, cpPairs{i}{2});
        %         addMetadata(data, cpPairs{i}{1});
        %     end
        % 
        %     % Process FP pairs
        %     for i = 1:length(fpPairs)
        %         data = processFPPair(fpPairs{i}{1}, fpPairs{i}{2});
        %         addMetadata(data, fpPairs{i}{1});
        %     end
        % end

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set the default value for the radio button group
            app.DetectionclassifierButtonGroup.SelectedObject = app.KERNOButton; % The default value for the classifier
            app.Classifier = app.DetectionclassifierButtonGroup.SelectedObject.Text;
            app.MooringtypeButtonGroup.SelectedObject = app.bottommooringButton ; % The default value for the mooring type input
            app.MooringType = app.MooringtypeButtonGroup.SelectedObject.Text;
            app.ResolutiontoextractButtonGroup.SelectedObject = app.DPMhourButton ; %The default value for the detection resolution input
            app.Resolution = app.ResolutiontoextractButtonGroup.SelectedObject.Text;

            % Initialize the metadata table in the startup function
            variableTypes = {'string','string','string','string','double','double','string','datetime','datetime','datetime','datetime','string'};
            variableNames = {'Filename', 'ProjectName', 'StationName','ReceiverName','Latitude','Longitude','MooringType','DeploymentDatetime', 'RecoveryDatetime', 'ActivationDatetime', 'ValidUntilDatetime','Classifier'};
            app.MetadataTable = table('Size', [0, 12], ...
                              'VariableTypes', variableTypes, ...
                              'VariableNames', variableNames);

            app.CurrentFileIndex = 1; % Start with the first file


        end

        % Button pushed function: ChoosefilestouploadButton
        function ChoosefilestouploadButtonPushed(app, event)
            % ===============
            % File selection 
            % ===============
            % Allows user to upload several files from the same deployment

            [app.files, path] = uigetfile('*.*', 'MultiSelect','on') ; % function to select multiple files

            % get just the files that end in .CP3 or .FP3
            if isequal(app.files, 0)
                disp('User selected Cancel');
            else
                if ischar(app.files)
                    app.files = {app.files}; % ensure files is in a cell array
                end
                % app.files ; % can maybe be deleted
                app.SelectedFilePath = fullfile(path, app.files);
                app.updateTextArea(app.files); % Display file names in the app window

                % Check the files: 
                % - do they have the correct file format
                % - have the corresponding files in a pair been selected
                checkFiles(app, app.SelectedFilePath);

                % Enable resolution selection
                % OBS: if user does not change the default is DPM/hour (can
                % be changed using the startupFcn
                app.DPSButton.Enable = 'on';
                app.DPMButton.Enable = "on";
                app.DPMhourButton.Enable ="on";
                app.DPHButton.Enable = "on";
                app.filtFiles = app.files(~endsWith(app.files, '1')); % make filtered list of filenames by removing all .CP1 and .FP1 files

            end

        end

        % Selection changed function: ResolutiontoextractButtonGroup
        function ResolutiontoextractButtonGroupSelectionChanged(app, event)
            
            % TO DO: make sure the value of this variable matches what is
            % required from the function cpodat3_2_etn
            selectedButton = app.ResolutiontoextractButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'DPS'
                    app.Resolution = 'seconds';
                case 'DPM'
                    app.Resolution = 'minutes';
                case 'DPM/hour'
                    app.Resolution = 'minutes';
                case 'DPH'
                    app.Resolution = 'hours';
            end
        end

        % Button pushed function: ConfirmselectionButton
        function ConfirmselectionButtonPushed(app, event)
            % ===================================================
            % Confirm selection of files and detection resolution
            % ===================================================

            % Switch to the metadata tab
            app.TabGroup.SelectedTab = app.MetadatainputTab;
            
            % Display the filename of the first CP3 or FP3 file:
            % TO DO: add a text field to display current file name

            % Enable all metadata input fields and disable file selection
            app.KERNOButton.Enable = 'on';
            app.HEL1Button.Enable = 'on';
            app.OtherPleasespecifyButton.Enable = 'on';
            app.ActivationdatetimeEditField.Enable = 'on';
            app.DatavaliduntildatetimeEditField.Enable = 'on';
            app.RecoverydatetimeEditField.Enable = 'on';
            app.DeploymentdatetimeEditField.Enable = 'on';
            app.LongitudeEditField.Enable = 'on';
            app.LatitudeEditField.Enable = 'on';
            app.ReceivernameEditField.Enable = 'on';
            app.StationnameEditField.Enable = 'on';
            app.ProjectnameEditField.Enable = 'on';
            app.SurfacebuoyButton.Enable = 'on';
            app.bottommooringButton.Enable = 'on';
            app.ChoosefilestouploadButton.Enable = 'off';
            app.DPSButton.Enable = 'off';
            app.DPMButton.Enable = "off";
            app.DPMhourButton.Enable = "off";
            app.DPHButton.Enable = "off";
            
            % Display the first file on the filtered file list - this will
            % be the first file thw user has to add metadata for
            app.updateFileDisplay()


        end

        % Value changed function: ProjectnameEditField
        function ProjectnameEditFieldValueChanged(app, event)
            
            app.ProjectName = app.ProjectnameEditField.Value;
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            
        end

        % Value changed function: StationnameEditField
        function StationnameEditFieldValueChanged(app, event)
            
            app.StationName = app.StationnameEditField.Value;
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            
        end

        % Value changed function: ReceivernameEditField
        function ReceivernameEditFieldValueChanged(app, event)
            
            app.ReceiverName = app.ReceivernameEditField.Value;
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged2(app, event)
            
            app.Latitude = str2double(app.LatitudeEditField.Value);
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out


        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged2(app, event)
            
            app.Longitude = str2double(app.LongitudeEditField.Value);
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out

            
        end

        % Selection changed function: MooringtypeButtonGroup
        function MooringtypeButtonGroupSelectionChanged(app, event)
            
            app.MooringType = app.MooringtypeButtonGroup.SelectedObject.Text;

            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            
        end

        % Value changed function: DeploymentdatetimeEditField
        function DeploymentdatetimeEditFieldValueChanged(app, event)
            
            app.Deploy_datetime = app.DeploymentdatetimeEditField.Value;
            
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            

        end

        % Value changed function: RecoverydatetimeEditField
        function RecoverydatetimeEditFieldValueChanged(app, event)
            
            app.Recover_datetime = app.RecoverydatetimeEditField.Value ;

            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out


        end

        % Value changed function: ActivationdatetimeEditField
        function ActivationdatetimeEditFieldValueChanged(app, event)
            
            app.Active_datetime = app.ActivationdatetimeEditField.Value;

            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out

            
        end

        % Value changed function: DatavaliduntildatetimeEditField
        function DatavaliduntildatetimeEditFieldValueChanged(app, event)
            
            app.Valid_data_until_datetime = app.DatavaliduntildatetimeEditField.Value;

            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out


        end

        % Selection changed function: DetectionclassifierButtonGroup
        function DetectionclassifierButtonGroupSelectionChanged(app, event)
            
            app.Classifier = app.DetectionclassifierButtonGroup.SelectedObject.Text;

            app.validateInputs();% sends the output to a function that checks if all mandatory fields have been filled out
            
        end

        % Button pushed function: NextfileButton
        function NextfileButtonPushed(app, event)
            % =======================
            % Validate metadata input
            % =======================

            % Validate latitude and longitude inputs
            [isValidCoords, coordErrorMsg] = app.validateCoordinates();
          
            % Validate datetime relationships
            [isValidDatetime, datetimeErrorMsg] = app.validateDatetimeRelationships();
            
            % Collect all error messages
            errorMsg = [coordErrorMsg, datetimeErrorMsg];
            
            % Check if any validation failed
            if ~isValidCoords || ~isValidDatetime
                uialert(app.UIFigure, sprintf(errorMsg), 'Error');
                return; % halt execution if validation check failed
            end

            % Create a pop-up dialogue with Confirm and Cancel buttons
            % If user Confirms: send them to next file - if no next file
            % exists send them to upload tab
            % If user Cancels: they can add or change metadata input for
            % that specific file
            choice = uiconfirm(app.UIFigure, 'Do you want to confirm the metadata?', 'Confirm Metadata', ...
                'Options', {'Confirm', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 2);
            
            % ======================
            % Gather metadata inputs
            % ======================

            % Handle the user's response
            switch choice
                case 'Confirm' % If the user confirms their metadata input:
                    % Proceed with confirmation
                    disp('Metadata confirmed:');
                        % Retrieve metadata 
                        [~, filename, ext] = fileparts(app.filtFiles{app.CurrentFileIndex});
                        newRow = {filename, app.ProjectName,app.StationName,app.ReceiverName,app.Latitude, app.Longitude,string(app.MooringType),...
                            datetime(app.Deploy_datetime,'InputFormat', 'yyyy-MM-dd HH:mm:ss'), datetime(app.Recover_datetime,'InputFormat', 'yyyy-MM-dd HH:mm:ss'),...
                            datetime(app.Active_datetime,'InputFormat', 'yyyy-MM-dd HH:mm:ss'),datetime(app.Valid_data_until_datetime,'InputFormat', 'yyyy-MM-dd HH:mm:ss'),...
                            app.Classifier}; % Adjust for your specific columns
                        % Add the metadata as a new row in the table
                        app.MetadataTable = [app.MetadataTable; newRow];
                        disp(app.MetadataTable); % DEBUGGING
                        % Clear the input fields for the next entry
                            app.ActivationdatetimeEditField.Value = '';
                            app.DatavaliduntildatetimeEditField.Value = '';
                            app.RecoverydatetimeEditField.Value = '';
                            app.DeploymentdatetimeEditField.Value = '';
                            app.LongitudeEditField.Value = '';
                            app.LatitudeEditField.Value = '';
                            app.ReceivernameEditField.Value = '';
                            app.StationnameEditField.Value = '';
                            app.ProjectnameEditField.Value = '';

                        % Evaluate if there are files left to fill out
                        % Move to the next file
                        app.CurrentFileIndex = app.CurrentFileIndex + 1;

                        % Update the file display
                        if app.CurrentFileIndex <= length(app.filtFiles)  % if there are still files to do update the display to the next file name
                            app.updateFileDisplay();
                        else % if there are no more files go to next tab, enable upload button and disable all metadat fields
                            app.NextfileButton.Enable = 'off';
                            % Switch to the third tab
                            app.TabGroup.SelectedTab = app.UploadTab;
                            % Enable the upload button
                            app.UploadfilestodatabaseButton.Enable = true; % Enable the Upload button

                            % Disable all input components on the second tab
                            app.KERNOButton.Enable = 'off';
                            app.HEL1Button.Enable = 'off';
                            app.OtherPleasespecifyButton.Enable = 'off';
                            app.ActivationdatetimeEditField.Enable = 'off';
                            app.DatavaliduntildatetimeEditField.Enable = 'off';
                            app.RecoverydatetimeEditField.Enable = 'off';
                            app.DeploymentdatetimeEditField.Enable = 'off';
                            app.LongitudeEditField.Enable = 'off';
                            app.LatitudeEditField.Enable = 'off';
                            app.ReceivernameEditField.Enable = 'off';
                            app.StationnameEditField.Enable = 'off';
                            app.ProjectnameEditField.Enable = 'off';
                            app.bottommooringButton.Enable = 'off';
                            app.SurfacebuoyButton.Enable = 'off';
                            app.NextfileButton.Enable = 'off';
                        end
                case 'Cancel' % If the user does not confirm the metadata input
                    % Allow user to modify inputs
                    disp('Metadata input cancelled. Please modify the inputs as needed.');
            end
        end

        % Button pushed function: UploadfilestodatabaseButton
        function UploadfilesButtonPushed(app, event)
            
            % =======================
            % Extract and upload data
            % =======================

            % At the moment this will be the most labour-intensive chunk:
            %
            % 1. It extracts the POD data sequentially - either using
            % Mel & Jakob's function (CPOD files) or Jamie's Matlab wrapper (FPOD files) 
            % 
            % 2. Extract and summarise the data required for the ETN database and 
            % reformat to match the database.
            %
            % 3. Apply the metadata input from the user based on the filename.
            %
            % 4. Push data to be ingested into the ETN database (TBD).

            % ===========================================================
            
            % Display message when the upload button is pressed
            app.MessageTextArea.Value = 'Importing data...';

            %================
            % Import POD data
            %================

            % Check the list of files if there are both C/FPOD files


            % Make a list of file pairs CP1/3 and/or FP1/3 to import and
            % summarise

            % For each file pair:
            % % - Import data from both XP1 and XP3 file
            % % - Run summarising/formatting function depending on file
            % type
            % % - Add metadata based on file name

            % Check for server connection

            % Send formatted data to database

            % Evaluate if any files are left for processing


            % OBS: The database code will change when we know the target
            % database!!!
           
            % Establish a connection to the SQL Server
            % Define the DSN-less connection string
            connStr = 'Driver={SQL Server};Server=D54296\SQLEXPRESS;Database=mydatabase;Trusted_Connection=yes;';

            % Establish the database connection using the DSN-less connection string
            app.conn = odbc(connStr);


            if isopen(app.conn) % Check if connection to server is open

                % create a waitbar to show the progress
                h = waitbar(0, 'Reading and uploading files...') ;
                totalFiles = numel(app.SelectedFilePath);
                for i = 1:totalFiles
                    
                    % Update message for reading file
                    app.MessageTextArea.Value = sprintf('Reading file %d of %d...', i, totalFiles);
                    
                    % ====================
                    % Extract POD data
                    % ====================
                    % TO DO: split up import based on whether it is C-POD
                    % data or F-POD data

                    % Read files
                    % % TO DO: ensure that the corresponding XP1/XP3 files
                    % are extracted and summaries are put together
                    fileID = i;
                    Nsamples = 10 ;%  OBS: this is set to only read the first N data points to speed up tests and troubleshooting
                    pod3file = app.SelectedFilePath{i};
                    [pod3data] = importpoddata(pod3file, 'detstart', 0, 'maxdet', Nsamples); 
                    
                    % Update message for import
                    app.MessageTextArea.Value = sprintf(['Imported ' num2str(length(poddata)) ' clicks']);

                    % ==========================
                    % Run summarising functions
                    % ==========================
                    

                    % % Extract information from .CP3 or .FP3 files:
                    % ETN = cpodat3_2_etn(pod3data,app.Resolution,app.Active_datetime, app.Valid_data_until_datetime,onlyporp)
                    % The input in the function is
                    % % % pod3data = the output from importpoddata for .CP3
                    % or .FP3 files
                    % % % app.resolution = the detection resolution chosen
                    % by the uset
                    % % % app.Active_datatime = the time the recording
                    % began
                    % % % app.Valid_data_until_datetime = the time the
                    % recording ended.

                    % % Extract information from .CP1 or .FP1 files:
                    % nclicks = cpodat1_2_nclicks()

                    % =========================
                    % Apply metadata from input
                    % =========================

                    % TO DO: test the summarising function with the input
                    % from the metadata selection

                    % =============
                    % Upload files
                    % =============

                    % TO DO: is there a way to evaluate if a deployment has
                    % already been uploaded to the database to not have
                    % duplicate data?

                    % OBS: this section of code is just to shorten the name of
                    % the upload to make it work for the SQL database - can
                    % likely be removed for the final database
                    fileName = app.files{i};
                    fileName = strrep(fileName, ' ', ''); % remove spaces from file name
                    fileName = fileName(1:min(50, length(fileName)));

                    % Convert binary data to hexadecimal string for SQL insertion
                    % hexFileData = dec2hex(fileData);
                    % hexFileData = reshape(hexFileData', 1, []); % Convert to a single row
                    fileData=getByteStreamFromArray(poddata); % convert to byte array
                    hexFileData = reshape(dec2hex(fileData)', 1, []);% Convert to a single row

                    % Create the SQL query
                    sqlquery = sprintf('INSERT INTO Test (FileID, FileName, FileData) VALUES (%d, ''%s'', 0x%s)', fileID, fileName, hexFileData);

                    % Execute the SQL query with error handling
                    try
                        exec(app.conn, sqlquery);
                        
                    catch ME
                        fprintf('Error uploading', i, ME.message);
                    end

                    % Update waitbar
                    waitbar(i / totalFiles, h, sprintf('Processing file %d of %d...', i, totalFiles))
                end
                close(app.conn);
                % Close waitbar 
                close(h)
                uialert(app.UIFigure, 'Files uploaded successfully', 'Success');
            else
                uialert(app.UIFigure, ['Connection failed: ', app.conn.Message], 'Error');
            end

            % If upload is successful, disable the upload and enable the select button
            app.MessageTextArea.Value = 'Upload process completed.';
            app.ChoosefilestouploadButton.Enable = 'on';
            app.UploadfilestodatabaseButton.Enable = 'off';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileselectionTab = uitab(app.TabGroup);
            app.FileselectionTab.Title = 'File selection';
            app.GridLayout = uigridlayout(app.FileselectionTab);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {''; ''; '     Select .CP1/.CP3 or .FP1/.FP3 files belonging to the same deployment. '; '     Add metadata and upload to repository.'};
            app.ChoosefilestouploadButton = uibutton(app.GridLayout, 'push');
            app.ChoosefilestouploadButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefilestouploadButtonPushed, true);
            app.ChoosefilestouploadButton.Tooltip = {'Click to select .CP1, .CP3, .FP1 and/or .FP3 files to upload.'};
            app.ChoosefilestouploadButton.Text = 'Choose files to upload';
            app.FilesSelectedTextAreaLabel = uilabel(app.GridLayout);
            app.FilesSelectedTextAreaLabel.Text = 'Files Selected';
            app.FilesSelectedTextArea = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea.Editable = 'off';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'porpoise.jpg');
            app.ConfirmselectionButton = uibutton(app.GridLayout, 'push');
            app.ConfirmselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmselectionButtonPushed, true);
            app.ConfirmselectionButton.Enable = 'off';
            app.ConfirmselectionButton.Tooltip = {'Please select files to upload.'};
            app.ConfirmselectionButton.Text = 'Confirm selection';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.ResolutiontoextractButtonGroup = uibuttongroup(app.GridLayout);
            app.ResolutiontoextractButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResolutiontoextractButtonGroupSelectionChanged, true);
            app.ResolutiontoextractButtonGroup.Title = 'Resolution to extract';
            app.DPSButton = uiradiobutton(app.ResolutiontoextractButtonGroup);
            app.DPSButton.Enable = 'off';
            app.DPSButton.Text = 'DPS';
            app.DPMButton = uiradiobutton(app.ResolutiontoextractButtonGroup);
            app.DPMButton.Enable = 'off';
            app.DPMButton.Text = 'DPM';
            app.DPMhourButton = uiradiobutton(app.ResolutiontoextractButtonGroup);
            app.DPMhourButton.Enable = 'off';
            app.DPMhourButton.Text = 'DPM/hour';
            app.DPMhourButton.Value = true;
            app.DPHButton = uiradiobutton(app.ResolutiontoextractButtonGroup);
            app.DPHButton.Enable = 'off';
            app.DPHButton.Text = 'DPH';
            app.MetadatainputTab = uitab(app.TabGroup);
            app.MetadatainputTab.Title = 'Metadata input';
            app.MandatorymetadatainputPanel = uipanel(app.MetadatainputTab);
            app.MandatorymetadatainputPanel.Title = 'Mandatory metadata input';
            app.GridLayout2 = uigridlayout(app.MandatorymetadatainputPanel);
            app.MooringtypeButtonGroup = uibuttongroup(app.GridLayout2);
            app.MooringtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MooringtypeButtonGroupSelectionChanged, true);
            app.MooringtypeButtonGroup.Title = 'Mooring type';
            app.SurfacebuoyButton = uiradiobutton(app.MooringtypeButtonGroup);
            app.SurfacebuoyButton.Enable = 'off';
            app.SurfacebuoyButton.Text = 'Surface-buoy';
            app.bottommooringButton = uiradiobutton(app.MooringtypeButtonGroup);
            app.bottommooringButton.Enable = 'off';
            app.bottommooringButton.Text = 'bottom-mooring';
            app.bottommooringButton.Value = true;
            app.ProjectnameEditFieldLabel = uilabel(app.GridLayout2);
            app.ProjectnameEditFieldLabel.Tooltip = {'Name of the project for which the data was collected. '};
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectnameEditField = uieditfield(app.GridLayout2, 'text');
            app.ProjectnameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectnameEditFieldValueChanged, true);
            app.ProjectnameEditField.Enable = 'off';
            app.ProjectnameEditField.Tooltip = {''};
            app.ProjectnameEditField.Placeholder = 'E.g. Lifewatch';
            app.StationnameEditFieldLabel = uilabel(app.GridLayout2);
            app.StationnameEditFieldLabel.Tooltip = {'Name of the deployment station. This should refer to a unique location, related to a specific latitude and longitude.'};
            app.StationnameEditFieldLabel.Text = 'Station name';
            app.StationnameEditField = uieditfield(app.GridLayout2, 'text');
            app.StationnameEditField.ValueChangedFcn = createCallbackFcn(app, @StationnameEditFieldValueChanged, true);
            app.StationnameEditField.Enable = 'off';
            app.StationnameEditField.Tooltip = {''};
            app.StationnameEditField.Placeholder = 'E.g. bpns-Birkenfels';
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout2);
            app.LatitudeEditFieldLabel.Tooltip = {'The latitude of the station location in decimal degrees.'};
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.GridLayout2, 'text');
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged2, true);
            app.LatitudeEditField.Enable = 'off';
            app.LatitudeEditField.Tooltip = {''};
            app.LatitudeEditField.Placeholder = 'E.g. 55.2391';
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout2);
            app.LongitudeEditFieldLabel.Tooltip = {'The longitude of the station location in decimal degrees.'};
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.GridLayout2, 'text');
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged2, true);
            app.LongitudeEditField.Enable = 'off';
            app.LongitudeEditField.Tooltip = {''};
            app.LongitudeEditField.Placeholder = 'E.g. 7.6342';
            app.ReceivernameEditFieldLabel = uilabel(app.GridLayout2);
            app.ReceivernameEditFieldLabel.Tooltip = {'The name of the receiver composed of ''Model number''-''Serial number''.'};
            app.ReceivernameEditFieldLabel.Text = 'Receiver name';
            app.ReceivernameEditField = uieditfield(app.GridLayout2, 'text');
            app.ReceivernameEditField.ValueChangedFcn = createCallbackFcn(app, @ReceivernameEditFieldValueChanged, true);
            app.ReceivernameEditField.Enable = 'off';
            app.ReceivernameEditField.Tooltip = {''};
            app.ReceivernameEditField.Placeholder = 'E.g. POD-7106';
            app.DeploymentdatetimeEditFieldLabel = uilabel(app.GridLayout2);
            app.DeploymentdatetimeEditFieldLabel.Tooltip = {'The date and time the receiver was deployed. Format YYYY-MM-DD hh-mm-ss.'};
            app.DeploymentdatetimeEditFieldLabel.Text = 'Deployment datetime';
            app.DeploymentdatetimeEditField = uieditfield(app.GridLayout2, 'text');
            app.DeploymentdatetimeEditField.ValueChangedFcn = createCallbackFcn(app, @DeploymentdatetimeEditFieldValueChanged, true);
            app.DeploymentdatetimeEditField.Enable = 'off';
            app.DeploymentdatetimeEditField.Tooltip = {''};
            app.DeploymentdatetimeEditField.Placeholder = 'E.g. 2022-06-28 10:05:00';
            app.RecoverydatetimeEditFieldLabel = uilabel(app.GridLayout2);
            app.RecoverydatetimeEditFieldLabel.Tooltip = {'The date and time the receiver was recovered. Format YYYY-MM-DD hh-mm-ss.'};
            app.RecoverydatetimeEditFieldLabel.Text = 'Recovery date time';
            app.RecoverydatetimeEditField = uieditfield(app.GridLayout2, 'text');
            app.RecoverydatetimeEditField.ValueChangedFcn = createCallbackFcn(app, @RecoverydatetimeEditFieldValueChanged, true);
            app.RecoverydatetimeEditField.Enable = 'off';
            app.RecoverydatetimeEditField.Tooltip = {''};
            app.RecoverydatetimeEditField.Placeholder = 'E.g. 2022-11-28 12:15:00';
            app.DatavaliduntildatetimeEditFieldLabel = uilabel(app.GridLayout2);
            app.DatavaliduntildatetimeEditFieldLabel.Tooltip = {'The date and time until which valid data was recorded. Format YYYY-MM-DD hh-mm-ss.'};
            app.DatavaliduntildatetimeEditFieldLabel.Text = 'Data valid until datetime';
            app.DatavaliduntildatetimeEditField = uieditfield(app.GridLayout2, 'text');
            app.DatavaliduntildatetimeEditField.ValueChangedFcn = createCallbackFcn(app, @DatavaliduntildatetimeEditFieldValueChanged, true);
            app.DatavaliduntildatetimeEditField.Enable = 'off';
            app.DatavaliduntildatetimeEditField.Tooltip = {''};
            app.DatavaliduntildatetimeEditField.Placeholder = 'E.g. 2022-10-28 22:35:00';
            app.ActivationdatetimeEditFieldLabel = uilabel(app.GridLayout2);
            app.ActivationdatetimeEditFieldLabel.Tooltip = {'The date and time the receiver was activated. Format YYYY-MM-DD hh-mm-ss.'};
            app.ActivationdatetimeEditFieldLabel.Text = 'Activation date time';
            app.ActivationdatetimeEditField = uieditfield(app.GridLayout2, 'text');
            app.ActivationdatetimeEditField.ValueChangedFcn = createCallbackFcn(app, @ActivationdatetimeEditFieldValueChanged, true);
            app.ActivationdatetimeEditField.Enable = 'off';
            app.ActivationdatetimeEditField.Tooltip = {''};
            app.ActivationdatetimeEditField.Placeholder = 'E.g. 2022-06-30 00:00:00';
            app.DetectionclassifierButtonGroup = uibuttongroup(app.GridLayout2);
            app.DetectionclassifierButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DetectionclassifierButtonGroupSelectionChanged, true);
            app.DetectionclassifierButtonGroup.Tooltip = {'Only for .CP3 or .FP3 files. The detection classification algorithm applied to the .CP1 or .FP1 files. If a custom classifier was used please specify.'};
            app.DetectionclassifierButtonGroup.Title = 'Detection classifier';
            app.KERNOButton = uiradiobutton(app.DetectionclassifierButtonGroup);
            app.KERNOButton.Enable = 'off';
            app.KERNOButton.Text = 'KERNO';
            app.KERNOButton.Value = true;
            app.HEL1Button = uiradiobutton(app.DetectionclassifierButtonGroup);
            app.HEL1Button.Enable = 'off';
            app.HEL1Button.Text = 'HEL1';
            app.OtherPleasespecifyButton = uiradiobutton(app.DetectionclassifierButtonGroup);
            app.OtherPleasespecifyButton.Enable = 'off';
            app.OtherPleasespecifyButton.Text = 'Other (Please specify)';
            app.NextfileButton = uibutton(app.GridLayout2, 'push');
            app.NextfileButton.ButtonPushedFcn = createCallbackFcn(app, @NextfileButtonPushed, true);
            app.NextfileButton.Enable = 'off';
            app.NextfileButton.Tooltip = {'Enter all mandatory metadata fields'};
            app.NextfileButton.Text = 'Next file';
            app.CurrentfileTextAreaLabel = uilabel(app.GridLayout2);
            app.CurrentfileTextAreaLabel.Text = 'Current file';
            app.CurrentfileTextArea = uitextarea(app.GridLayout2);
            app.CurrentfileTextArea.Editable = 'off';
            app.OptionalmetadatainputPanel = uipanel(app.MetadatainputTab);
            app.OptionalmetadatainputPanel.Title = 'Optional metadata input';
            app.GridLayout3 = uigridlayout(app.OptionalmetadatainputPanel);
            app.UploadTab = uitab(app.TabGroup);
            app.UploadTab.Title = 'Upload';
            app.UploadfilestodatabaseButton = uibutton(app.UploadTab, 'push');
            app.UploadfilestodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfilesButtonPushed, true);
            app.UploadfilestodatabaseButton.Enable = 'off';
            app.UploadfilestodatabaseButton.Tooltip = {'Click to upload files. This may take a few minutes.'};
            app.UploadfilestodatabaseButton.Text = 'Upload files to database';
            app.MessageTextAreaLabel = uilabel(app.UploadTab);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UploadTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UploadApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end