classdef VALKYRIE < matlab.apps.AppBase
    %VALKYRIE Data aggregation tool for porpoise detection data
    %   The app allows batch upload of C-POD and F-POD data (CP1/3 and
    %   FP1/3 files) as well as metadata input related to each deployment
    %   into the European Tracking Network data repository with further
    %   ingestion into EDITOinfra.
    %   
    %   If agreed to, the input files will be stored in the Marine Data
    %   Archive.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FileselectionTab                matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ConfirmselectionButton          matlab.ui.control.Button
        FilesSelectedTextArea           matlab.ui.control.TextArea
        FilesSelectedTextAreaLabel      matlab.ui.control.Label
        ChoosefilestouploadButton       matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        MetadatainputTab                matlab.ui.container.Tab
        OptionalmetadatainputPanel      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        MooringtypeButtonGroup          matlab.ui.container.ButtonGroup
        SurfaceWindturbineButton        matlab.ui.control.RadioButton
        BottommooringButton             matlab.ui.control.RadioButton
        SurfaceBuoyButton               matlab.ui.control.RadioButton
        RecoverydatetimeEditField       matlab.ui.control.EditField
        RecoverydatetimeEditFieldLabel  matlab.ui.control.Label
        DatavaliduntildatetimeEditField  matlab.ui.control.EditField
        DatavaliduntildatetimeEditFieldLabel  matlab.ui.control.Label
        MandatorymetadatainputPanel     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        CurrentfileTextArea             matlab.ui.control.TextArea
        CurrentfileTextAreaLabel        matlab.ui.control.Label
        NextfileButton                  matlab.ui.control.Button
        ClassifierButtonGroup           matlab.ui.container.ButtonGroup
        NoSonarButton                   matlab.ui.control.RadioButton
        NoDolphinsButton                matlab.ui.control.RadioButton
        GenencButton                    matlab.ui.control.RadioButton
        HEL1Button                      matlab.ui.control.RadioButton
        KERNOFButton                    matlab.ui.control.RadioButton
        ActivationdatetimeEditField     matlab.ui.control.EditField
        ActivationdatetimeEditFieldLabel  matlab.ui.control.Label
        ReceivernameEditField           matlab.ui.control.EditField
        DeploymentdatetimeEditField     matlab.ui.control.EditField
        DeploymentdatetimeEditFieldLabel  matlab.ui.control.Label
        ReceivernameEditFieldLabel      matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.EditField
        StationnameEditField            matlab.ui.control.EditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.EditField
        ProjectnameEditField            matlab.ui.control.EditField
        StationnameEditFieldLabel       matlab.ui.control.Label
        LatitudeEditFieldLabel          matlab.ui.control.Label
        ProjectnameEditFieldLabel       matlab.ui.control.Label
        UploadTab                       matlab.ui.container.Tab
        MessageTextArea                 matlab.ui.control.TextArea
        MessageTextAreaLabel            matlab.ui.control.Label
        UploadfilestodatabaseButton     matlab.ui.control.Button
    end

    %============================
    % Properties for storing data
    %============================
    properties (Access = private)
        check
        SelectedFilePath % Path of the selected file
        conn             % Database connection
        files            % input file names
        path             % path to files to be used in CP3read_DTO   
        filtFiles        % filtered filenames
        cp1files
        cp3files
        fp1files
        fp3files

        % Metadata input values:
        ProjectName  
        StationName 
        ReceiverName 
        Latitude  
        Longitude 
        MooringType 
        Deploy_datetime  
        Recover_datetime 
        Active_datetime 
        Valid_data_until_datetime 
        Classifier 

        MetadataSchema
        Detections
        MetadataTable     % table to hold metadata for each file selected
        CurrentFileIndex  % index to keep track of each file selected
    end

    methods (Access = private)


        %========================================================
        % Function to check all mandatory metadata has been input
        %========================================================

        function validateInputs(app)
            % OBS: if-statement does not include the mooring or classifier.
            % re: input - will go ahead with default values if none is selected
            % WIll not evaluate if user has inpout any optional metadata
            if ~isempty(app.ProjectName) && ~isempty(app.StationName) && ~isempty(app.ReceiverName) && ~isempty(app.Latitude) && ~isempty(app.Longitude) && ~isempty(app.Deploy_datetime) && ~isempty(app.Active_datetime)
                app.NextfileButton.Enable = 'on';
            else
                app.NextfileButton.Enable = 'off';
            end
        end
        
        %============================================
        % Function to update the Current File display
        %============================================
        function updateFileDisplay(app)
            % Get just the filename without extension
            [~, name, ~] = fileparts(app.filtFiles{app.CurrentFileIndex});
            app.CurrentfileTextArea.Value = name;
        end
        
        %====================================================
        % Function to display text in text area of Upload tab
        %====================================================
        % TO DO: decide if this is redundant as there is now a progress bar
        % instead 
        function updateTextArea(app, files)
            app.FilesSelectedTextArea.Value = files ;
            numLines = numel(app.FilesSelectedTextArea.Value);
            app.FilesSelectedTextArea.Position(4) = 22 * numLines; % adjust height based on number of lines
        end

        %==================================================================
        % Function to verify logical sequence of datetime fields in the app
        %==================================================================
        function errorMsg = checkDatetimeFlow(app)
            
            errorMsg = '';
            try
                dep = datetime(app.DeploymentdatetimeEditField.Value,'InputFormat', 'yyyy-MM-dd HH:mm:ss', 'TimeZone', 'UTC');
            
                % Optional: Recovery
                recStr = strtrim(app.RecoverydatetimeEditField.Value);
                if ~isempty(recStr)
                    rec = datetime(recStr, 'InputFormat', 'yyyy-MM-dd HH:mm:ss', 'TimeZone', 'UTC');
                    app.RecoverydatetimeEditField.BackgroundColor = 'white';
                    if dep > rec % deployment is before recovery
                        errorMsg = [errorMsg,"- Deployment datetime must be before Recovery datetime."];
                        app.RecoverydatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red)
                    end
                end
        
                % Optional: Data Valid Until
                validStr = strtrim(app.DatavaliduntildatetimeEditField.Value);
                if ~isempty(validStr)
                    val = datetime(validStr, 'InputFormat', 'yyyy-MM-dd HH:mm:ss', 'TimeZone', 'UTC');
                    app.DatavaliduntildatetimeEditField.BackgroundColor = 'white';
                    if dep > val % deployment has to be before valid data until
                        errorMsg = [errorMsg,"- Deployment datetime must be before Data Valid Until datetime."];
                        app.DatavaliduntildatetimeEditField.BackgroundColor = [1 0.8 0.8]; % Light red)
                    end
                end
            catch ex
                errorMsg = [errorMsg,"- Error during datetime sequence validation."];
            end
        end

        %==================================================================
        % Function to format row to append to metadata table
        %==================================================================
        function newRow = buildMetadataRow(app)
            % Build a single metadata row using the app.MetadataSchema
            varNames = cellfun(@(x) x.Name, app.MetadataSchema, 'UniformOutput', false);
            rowData = cell(1, numel(app.MetadataSchema));
    
            for i = 1:numel(app.MetadataSchema)
                field = app.MetadataSchema{i};
                fieldName = field.Name;
                fieldType = field.Type;
                timeZone = '';
                if isfield(field, 'TimeZone')
                    timeZone = field.TimeZone;
                end
    
                % Map from UI fields or internal values to metadata fields
                switch fieldName
                    case 'Filename'
                        [~, filename, ~] = fileparts(app.filtFiles{app.CurrentFileIndex});
                        val = filename;
                    case 'ProjectName'
                        val = app.ProjectnameEditField.Value;
                    case 'StationName'
                        val = app.StationnameEditField.Value;
                    case 'ReceiverName'
                        val = app.ReceivernameEditField.Value;
                    case 'Latitude'
                        val = str2double(app.LatitudeEditField.Value);
                    case 'Longitude'
                        val = str2double(app.LongitudeEditField.Value);
                    case 'MooringType'
                        val = app.MooringType;
                    case 'DeploymentDateTime'
                        val = app.DeploymentdatetimeEditField.Value;
                    case 'RecoveryDateTime'
                        val = app.RecoverydatetimeEditField.Value;
                    case 'ActivationDateTime'
                        val = app.ActivationdatetimeEditField.Value;
                    case 'DataValidUntil'
                        val = app.DatavaliduntildatetimeEditField.Value;
                    case 'Classifier'
                        val = app.Classifier;
                    otherwise
                        val = '';
                end
    
                % Safe conversion based on type
                switch fieldType
                    case 'string'
                        rowData{i} = string(val);
                    case 'double'
                        if isempty(val) || isnan(val)
                            rowData{i} = NaN;
                        else
                            rowData{i} = double(val);
                        end
                    case 'datetime'
                        if isempty(val)
                            rowData{i} = NaT('TimeZone', timeZone);
                        else
                            dt = datetime(val);
                            if ~isempty(timeZone)
                                dt.TimeZone = timeZone;
                            end
                            rowData{i} = dt;
                        end
                end
            end
    
            % Return a table row ready to be appended
            newRow = cell2table(rowData, 'VariableNames', varNames);
        end

        %==================================================================
        % Function to reset the input fields in the metadata tab
        %==================================================================
        function resetMetadataInputs(app)
            for i = 1:numel(app.MetadataSchema)
                field = app.MetadataSchema{i};
                fieldName = field.Name;
    
                % Skip fields not tied to input (like 'Filename')
                switch fieldName
                    case {'Filename'}
                        continue
                    case 'ProjectName'
                        app.ProjectnameEditField.Value = '';
                    case 'StationName'
                        app.StationnameEditField.Value = '';
                    case 'ReceiverName'
                        app.ReceivernameEditField.Value = '';
                    case 'Latitude'
                        app.LatitudeEditField.Value = '';
                    case 'Longitude'
                        app.LongitudeEditField.Value = '';
                    case 'MooringType'
                        app.MooringType = ''; % or reset radio/group state if needed
                    case 'DeploymentDateTime'
                        app.DeploymentdatetimeEditField.Value = '';
                    case 'RecoveryDateTime'
                        app.RecoverydatetimeEditField.Value = '';
                    case 'ActivationDateTime'
                        app.ActivationdatetimeEditField.Value = '';
                    case 'DataValidUntil'
                        app.DatavaliduntildatetimeEditField.Value = '';
                    case 'Classifier'
                        app.Classifier = '';
                end
            end
        end
        
        %==================================================================
        % Function to disable all metadata input on the metadata tab
        %==================================================================       
        function disableMetadataInputs(app)
    
            % Text fields
            app.ProjectnameEditField.Enable = 'off';
            app.StationnameEditField.Enable = 'off';
            app.ReceivernameEditField.Enable = 'off';
            app.LatitudeEditField.Enable = 'off';
            app.LongitudeEditField.Enable = 'off';
    
            % Date/time fields
            app.DeploymentdatetimeEditField.Enable = 'off';
            app.RecoverydatetimeEditField.Enable = 'off';
            app.ActivationdatetimeEditField.Enable = 'off';
            app.DatavaliduntildatetimeEditField.Enable = 'off';
    
            % Buttons / selectors
            app.KERNOFButton.Enable = 'off';
            app.HEL1Button.Enable = 'off';
            app.GenencButton.Enable = 'off';
            app.BottommooringButton.Enable = 'off';
            app.SurfaceBuoyButton.Enable = 'off';
            app.NextfileButton.Enable = 'off';

        end
    end    


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('helpers')); % allow to look for functions in 'helpers' folder

            app.check = 1; % if = 1: will check if both C/FP1 and C/FP3 files are included

            % Set the default value for the radio button groups
            app.ClassifierButtonGroup.SelectedObject = app.KERNOFButton; % The default value for the classifier
            app.Classifier = app.ClassifierButtonGroup.SelectedObject.Text; % the default value for the Classifier if user has not interacted

            app.MooringtypeButtonGroup.SelectedObject = app.BottommooringButton ; % The default button to be 'selected' 
            app.MooringType = ''; % value of mooring type if user has not interacted with the buttons

            % Default value for optional datetime metadata
            app.RecoverydatetimeEditField.Value = ''; % give the optional metadata a value
            app.DatavaliduntildatetimeEditField.Value = '';

            app.CurrentFileIndex = 1; % Start with the first file

            % Initialize the metadata table in the startup function
            % % Schema for metadata:
            app.MetadataSchema = {
                struct('Name', 'Filename',               'Type', 'string')
                struct('Name', 'ProjectName',            'Type', 'string')
                struct('Name', 'StationName',            'Type', 'string')
                struct('Name', 'ReceiverName',           'Type', 'string')
                struct('Name', 'Latitude',               'Type', 'double')
                struct('Name', 'Longitude',              'Type', 'double')
                struct('Name', 'MooringType',            'Type', 'string')
                struct('Name', 'DeploymentDateTime',     'Type', 'datetime','TimeZone', 'UTC')
                struct('Name', 'RecoveryDateTime',       'Type', 'datetime','TimeZone', 'UTC')
                struct('Name', 'ActivationDateTime',     'Type', 'datetime','TimeZone', 'UTC')
                struct('Name', 'DataValidUntil',         'Type', 'datetime','TimeZone', 'UTC')
                struct('Name', 'Classifier',             'Type', 'string')
            };

            % Initialise an empty table based on the Metadata schema above
            varNames = cellfun(@(x) x.Name, app.MetadataSchema, 'UniformOutput', false);
            emptyData = cell(1, numel(app.MetadataSchema));
            
            for i = 1:numel(app.MetadataSchema)
                field = app.MetadataSchema{i};
                switch app.MetadataSchema{i}.Type
                    case 'string'
                        emptyData{i} = strings(0, 1);
                    case 'double'
                        emptyData{i} = NaN(0, 1);
                    case 'datetime'
                        dt = datetime.empty(0, 1);
                        if isfield(field, 'TimeZone') && ~isempty(field.TimeZone)
                            dt.TimeZone = field.TimeZone;  % Set the time zone AFTER creation
                        end
                        emptyData{i} = dt;
                    otherwise
                        error("Unsupported type: " + app.MetadataSchema{i}.Type);
                end
            end
            
            app.MetadataTable = table(emptyData{:}, 'VariableNames', varNames);

            % 
            % % TO DO: reconsider this - this is super inflexible!
            % variableTypes = {'string','string','string','string','double','double','string','datetime','datetime','datetime','datetime','string'};
            % variableNames = {'Filename', 'ProjectName', 'StationName','ReceiverName','Latitude','Longitude','MooringType','DeploymentDatetime', 'RecoveryDatetime', 'ActivationDatetime', 'ValidUntilDatetime','Classifier'};
            % app.MetadataTable = table('Size', [0, 12], ...
            %                   'VariableTypes', variableTypes, ...
            %                   'VariableNames', variableNames);
            % app.Detections = table('Size', [0,12], ...
            %     'VariableTypes',{'string','string','datetime','double','double','double','double','double','double','double','double','string'});


        end

        % Button pushed function: ChoosefilestouploadButton
        function ChoosefilestouploadButtonPushed(app, event)
            % ===============
            % File selection 
            % ===============
            % Allows user to upload several files from the same deployment

            [app.files, app.path] = uigetfile('*.*', 'MultiSelect','on') ; % function to select multiple files

            % get just the files that end in .CP3 or .FP3
            if isequal(app.files, 0)
                disp('User selected Cancel');
                return
            end
            
            if ischar(app.files)
                app.files = {app.files}; % ensure files is in a cell array
            end
                
            app.SelectedFilePath = fullfile(app.path, app.files);
            app.updateTextArea(app.files); % Display file names in the app window

            % Check the files: 
            % - do they have the correct file format
            % - have the corresponding files in a pair been selected
            % the second input variable used to let function know if it should check for filepairs 
            [isValid, fileGroups, msg] = checkFileExtension(app.SelectedFilePath, 1);

            if ~isValid 
                uialert(app.UIFigure, msg, 'File type error');
                return
            end

            % Store valid file sets and enable confirm
            app.cp1files = fileGroups.cp1;
            app.cp3files = fileGroups.cp3;
            app.fp1files = fileGroups.fp1;
            app.fp3files = fileGroups.fp3;
            app.ConfirmselectionButton.Enable = 'on';

            % Filter for metadata entry (.CP3 or .FP3)
            app.filtFiles = app.files(~endsWith(app.files, '1')); % make filtered list of filenames by removing all .CP1 and .FP1 files

        end

        % Button pushed function: ConfirmselectionButton
        function ConfirmselectionButtonPushed(app, event)
            % ===================================================
            % Confirm selection of files
            % ===================================================

            % Switch to the metadata tab
            app.TabGroup.SelectedTab = app.MetadatainputTab;
            
            % Display the filename of the first CP3 or FP3 file:
            % TO DO: add a text field to display current file name
            [~, ~, ext] = fileparts(app.filtFiles{app.CurrentFileIndex}); % get filename and extension of current file
            app.updateFileDisplay();

            % Enable all metadata input fields and disable file selection
            if ext == '.CP3'
                app.KERNOFButton.Enable = 'on';
                app.HEL1Button.Enable = 'on'; 
                app.GenencButton.Enable = 'on';
            else
                app.KERNOFButton.Enable = 'on';
                % TO DO: are the No Dolphin and No Sonar something that
                % should be chosen in addition to the KERNO-F classifier???
                app.NoDolphinsButton.Enable = 'on'; 
                app.NoSonarButton.Enable = 'on';   
            end
            
            app.ActivationdatetimeEditField.Enable = 'on';
            app.DatavaliduntildatetimeEditField.Enable = 'on';
            app.RecoverydatetimeEditField.Enable = 'on';
            app.DeploymentdatetimeEditField.Enable = 'on';
            app.LongitudeEditField.Enable = 'on';
            app.LatitudeEditField.Enable = 'on';
            app.ReceivernameEditField.Enable = 'on';
            app.StationnameEditField.Enable = 'on';
            app.ProjectnameEditField.Enable = 'on';
            app.SurfaceBuoyButton.Enable = 'on';
            app.BottommooringButton.Enable = 'on';
            app.ChoosefilestouploadButton.Enable = 'off';
            
            % Display the first file on the filtered file list - this will
            % be the first file thw user has to add metadata for
            app.updateFileDisplay()


        end

        % Value changed function: ProjectnameEditField
        function ProjectnameEditFieldValueChanged(app, event)
            
            app.ProjectName = app.ProjectnameEditField.Value;
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            
        end

        % Value changed function: StationnameEditField
        function StationnameEditFieldValueChanged(app, event)
            
            app.StationName = app.StationnameEditField.Value;
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            
        end

        % Value changed function: ReceivernameEditField
        function ReceivernameEditFieldValueChanged(app, event)
            
            app.ReceiverName = app.ReceivernameEditField.Value;
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged2(app, event)
            
            app.Latitude = str2double(app.LatitudeEditField.Value);
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out


        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged2(app, event)
            
            app.Longitude = str2double(app.LongitudeEditField.Value);
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out

            
        end

        % Selection changed function: MooringtypeButtonGroup
        function MooringtypeButtonGroupSelectionChanged(app, event)
            
            app.MooringType = app.MooringtypeButtonGroup.SelectedObject.Text;

            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            
        end

        % Value changed function: DeploymentdatetimeEditField
        function DeploymentdatetimeEditFieldValueChanged(app, event)
            
            app.Deploy_datetime =app.DeploymentdatetimeEditField.Value;
            
            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out
            

        end

        % Value changed function: RecoverydatetimeEditField
        function RecoverydatetimeEditFieldValueChanged(app, event)
            
            app.Recover_datetime = app.RecoverydatetimeEditField.Value ;

            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out


        end

        % Value changed function: ActivationdatetimeEditField
        function ActivationdatetimeEditFieldValueChanged(app, event)
            
            app.Active_datetime = app.ActivationdatetimeEditField.Value;

            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out

            
        end

        % Value changed function: DatavaliduntildatetimeEditField
        function DatavaliduntildatetimeEditFieldValueChanged(app, event)
            
            app.Valid_data_until_datetime = app.DatavaliduntildatetimeEditField.Value;

            app.validateInputs(); % sends the output to a function that check if all mandatory fields have been filled out


        end

        % Selection changed function: ClassifierButtonGroup
        function ClassifierButtonGroupSelectionChanged(app, event)
            
            app.Classifier = app.ClassifierButtonGroup.SelectedObject.Text;

            app.validateInputs();% sends the output to a function that checks if all mandatory fields have been filled out
            
        end

        % Button pushed function: NextfileButton
        function NextfileButtonPushed(app, event)
            % =======================
            % Validate metadata input
            % =======================
            
            % The user will be able to click the Nex file button once all
            % mandatory metadata fields have been filled out. (Will not
            % evaluate on the Classifier button, but it will use the
            % default input)

            % Before the user can add metadata for the next
            % file/deployment, the app will check that coordinates and
            % datetime are in the correct format. 
            % If the user has added any optional metadata that will also be
            % checked. 
            % It will also check the timing of the datetime input based on
            % the following rules: 
            % 1. Deployment datetime is before or equal to activation
            % datetime
            % If other datetime data is included:
            % 2. Deployment is before both recovery and valid until
            % datetime

            errorMsg = '';

            % Validate latitude and longitude inputs
            [bgColorLat, bgColorLon, msg] = validateCoordinates(app.LatitudeEditField.Value, app.LongitudeEditField.Value);
            app.LatitudeEditField.BackgroundColor = bgColorLat; % change background color of the lat and lon fields if the input was wrong
            app.LongitudeEditField.BackgroundColor = bgColorLon;
            errorMsg = [errorMsg, msg];
            disp(errorMsg)

            % Validate datetime format
            % Required fields
            requiredFields = {
                'DeploymentdatetimeEditField', 'Deployment';
                'ActivationdatetimeEditField', 'Activation'
            };
            
            % Optional fields
            optionalFields = {
                'RecoverydatetimeEditField', 'Recovery';
                'DatavaliduntildatetimeEditField', 'Data Valid Until'
            };

            minDate = datetime(1990, 1, 1,'TimeZone', 'UTC'); % minimum date to check user input against

            % Validate required
            for i = 1:size(requiredFields,1)
                val = app.(requiredFields{i,1}).Value;
                [msg, fmt] = validateDatetime(val,minDate);
                disp(fmt.TimeZone) % DEBUGGING
                if strlength(msg) == 0
                    app.(requiredFields{i,1}).Value = fmt;
                    app.(requiredFields{i,1}).BackgroundColor = 'white';
                else
                    errorMsg = [errorMsg, msg];
                    app.(requiredFields{i,1}).BackgroundColor = [1 0.8 0.8]; % Light red)
                end
            end
            disp(errorMsg)

            % Validate optional
            for i = 1:size(optionalFields,1)
                val = strtrim(app.(optionalFields{i,1}).Value);
                if ~isempty(val)
                    [msg, fmt] = validateDatetime(val,minDate);
                    if strlength(msg) == 0
                        app.(optionalFields{i,1}).Value = fmt;
                        app.(optionalFields{i,1}).BackgroundColor = 'white';
                    else
                        errorMsg = [errorMsg, msg];
                        app.(optionalFields{i,1}).BackgroundColor = [1 0.8 0.8]; % Light red)
                    end
                end
            end
            disp(errorMsg)

            % Validate the timing of datetime entries
            msg = checkDatetimeFlow(app);
            errorMsg = [errorMsg, msg];
            disp(strlength(errorMsg))
            disp(errorMsg)

            % Check if any validation failed
            if ~isempty(errorMsg)
                uialert(app.UIFigure, errorMsg, 'Metadata input errors');
                return; % halt execution if validation check failed
            end

            % Create a pop-up dialogue with Confirm and Cancel buttons
            % If user Confirms: send them to next file - if no next file
            % exists send them to upload tab
            % If user Cancels: they can add or change metadata input for
            % that specific file
            choice = uiconfirm(app.UIFigure, 'Do you want to confirm the metadata?', 'Confirm Metadata', ...
                'Options', {'Confirm', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 2);
            
            % ======================
            % Gather metadata inputs
            % ======================

            % Handle the user's response
            switch choice
                case 'Confirm' % If the user confirms their metadata input:
                    % Proceed with confirmation
                    disp('Metadata confirmed:');
                        % add new row to metadata table
                        newRow = app.buildMetadataRow();

                        disp(newRow.DeploymentDateTime.TimeZone) % DEBUGGING


                        app.MetadataTable = [app.MetadataTable; newRow];
                        disp(app.MetadataTable); % DEBUGGING

                        % % Retrieve metadata 
                        % [~, filename, ~] = fileparts(app.filtFiles{app.CurrentFileIndex});
                        % newRow = {filename, app.ProjectName,app.StationName,app.ReceiverName,app.Latitude, app.Longitude,string(app.MooringType),...
                        %     app.DeploymentdatetimeEditField.Value, app.RecoverydatetimeEditField.Value,...
                        %     app.ActivationdatetimeEditField.Value,app.DatavaliduntildatetimeEditField.Value,...
                        %     app.Classifier}; % Adjust for your specific columns
                        % % Add the metadata as a new row in the table
                        % app.MetadataTable = [app.MetadataTable; newRow];
                        
                        % % Clear the input fields for the next entry
                        %     app.ActivationdatetimeEditField.Value = '';
                        %     app.DatavaliduntildatetimeEditField.Value = '';
                        %     app.RecoverydatetimeEditField.Value = '';
                        %     app.DeploymentdatetimeEditField.Value = '';
                        %     app.LongitudeEditField.Value = '';
                        %     app.LatitudeEditField.Value = '';
                        %     app.ReceivernameEditField.Value = '';
                        %     app.StationnameEditField.Value = '';
                        %     app.ProjectnameEditField.Value = '';
                        app.resetMetadataInputs();  % Clear the fields

                      % % Evaluate if there are files left to fill out
                        % Move to the next file
                        app.CurrentFileIndex = app.CurrentFileIndex + 1;

                        % Update the file display
                        if app.CurrentFileIndex <= length(app.filtFiles)  % if there are still files to do update the display to the next file name
                            app.updateFileDisplay();
                        else % if there are no more files go to next tab, enable upload button and disable all metadata fields
                            app.NextfileButton.Enable = 'off';
                            % Switch to the third tab
                            app.TabGroup.SelectedTab = app.UploadTab;
                            % Enable the upload button
                            app.UploadfilestodatabaseButton.Enable = true; % Enable the Upload button

                            % % Disable all input components on the second tab
                            % app.KERNOFButton.Enable = 'off';
                            % app.HEL1Button.Enable = 'off';
                            % app.GenencButton.Enable = 'off';
                            % app.ActivationdatetimeEditField.Enable = 'off';
                            % app.DatavaliduntildatetimeEditField.Enable = 'off';
                            % app.RecoverydatetimeEditField.Enable = 'off';
                            % app.DeploymentdatetimeEditField.Enable = 'off';
                            % app.LongitudeEditField.Enable = 'off';
                            % app.LatitudeEditField.Enable = 'off';
                            % app.ReceivernameEditField.Enable = 'off';
                            % app.StationnameEditField.Enable = 'off';
                            % app.ProjectnameEditField.Enable = 'off';
                            % app.BottommooringButton.Enable = 'off';
                            % app.SurfaceBuoyButton.Enable = 'off';
                            % app.NextfileButton.Enable = 'off';
                            app.disableMetadataInputs();
                        end
                case 'Cancel' % If the user does not confirm the metadata input
                    % Allow user to modify inputs
                    disp('Metadata input cancelled. Please modify the inputs as needed.');
            end
        end

        % Button pushed function: UploadfilestodatabaseButton
        function UploadfilesButtonPushed(app, event)
            
            % =======================
            % Extract and upload data
            % =======================

            % At the moment this will be the most labour-intensive chunk:
            %
            % 1. It extracts the POD data sequentially - using the
            % CPODread_DTO or FPODread_DTO depending on the input files.
            % 
            % 2. Extract and summarise the detection data required for the 
            % ETN database and reformat to match the database. The output
            % will be a single text file with all detections from all files
            % uploaded by a user - will have an identifier to match it to
            % metadata
            %
            % 3. Gather the metadata input from the user based on the
            % filename and generates a text file with metadata for all
            % processed files.
            %
            % 4. Push data to be ingested into the ETN database (TBD). It
            % will be send as text files: one with metadata info and one
            % with extracted detection data.

            % ===========================================================
            
            % Display message when the upload button is pressed
            app.MessageTextArea.Value = 'Importing data...';

            %================
            % Import POD data
            %================

            % For each file pair:
            % % - Import data from both XP1 and XP3 file
            % % - Run summarising/formatting function depending on file
            % type

            % Check for server connection

            % Send formatted data to database

            % Evaluate if any files are left for processing


            % % OBS: The database code will change when we know the target
            % % database!!!
            % 
            % % Establish a connection to the SQL Server
            % % Define the DSN-less connection string
            % connStr = 'Driver={SQL Server};Server=D54296\SQLEXPRESS;Database=mydatabase;Trusted_Connection=yes;';
            % 
            % % Establish the database connection using the DSN-less connection string
            % app.conn = odbc(connStr);

            % create a waitbar to show the progress
            h = waitbar(0, 'Reading and uploading files...') ;
            
            % TO DO: set up error messages to be displayed:
            % % Found error in extracting the data in the POD reader
            % % Error in the connection to the server

            % Specififies if the nall will be calculated (only if C/FP1
            % files also uploaded.
            n = '';
            if app.check == 1
                n = '-n';
            end
            
            % Create a combined table with detections - identifiable by the
            % filename:
            for i = 1:numel(app.filtFiles)
                
                % Update message for reading file
                app.MessageTextArea.Value = sprintf('Reading file %d of %d...', i, numel(app.filtFiles));
               
                % get filename and extension
                [~, filename, ext] = fileparts(app.filtFiles{i});


                % ====================================
                % Read files and summarising functions
                % ====================================
                % Get the filepath to the 
                % % extract and format files depending on their extension
                switch ext
                    case '.CP3'
                        % Import CP3 (and CP1) file and convert to ETN format
                        outputminutes = CP3read_DTO(filename); 
                        ETN = CP3read_2_etn(outputminutes); 
                    case '.FP3'
                        % Import FP3 (and FP1) file and convert to ETN
                        % format
                        outputminutes = FP3read_DTO(filename);
                        ETN = FP3read_2_etn(outputminutes);
                end


                ETN = addvars(ETN, repmat({filename}, height(ETN), 1), 'NewVariableNames', 'filename', 'Before', 1); % add filename identifier
                ETN = addvars(ETN, repmat({ext}, height(ETN), 1), 'NewVariableNames', 'podType', 'Before', 1); % add column with pod type

                % TO DO: Check if match between metadata and detections
                % - and what to do if there is a mismatch!
                %if dateshift(app.Active_datetime,'start', 'day') ~= dateshift(ETN.time(1), 'start', 'day') && dateshift(app.Valid_data_until_datetime,'start', 'day') ~= dateshift(ETN.time(end), 'start', 'day')
                %    
                %end

                % Append detection data
                app.Detections = [app.Detections;ETN];

                % Update waitbar
                waitbar(i / numel(app.filtFiles), h, sprintf('Processing file %d of %d...', i, numel(app.filtFiles)))
            end

           % Close waitbar 
            close(h)     
            
            disp(app.Detections) %DEBUGGING

            % if isopen(app.conn) % Check if connection to server is open
            % 
            %     % =============
            %     % Upload files
            %     % =============
            % 
            %     % Upload the metadata file and the detections data file to
            %     % the ETN database.
            %     % TO DO: how do we send it to the database as textfiles? 
            % 
            %     % TO DO: is there a way to evaluate if a deployment has
            %     % already been uploaded to the database to not have
            %     % duplicate data?
            % 
            %     % OBS: this section of code is just to shorten the name of
            %     % the upload to make it work for the SQL database - can
            %     % likely be removed for the final database
            %     fileName = app.filtFiles{i};
            %     fileName = strrep(fileName, ' ', ''); % remove spaces from file name
            %     fileName = fileName(1:min(50, length(fileName)));
            % 
            %     % Convert binary data to hexadecimal string for SQL insertion
            %     % hexFileData = dec2hex(fileData);
            %     % hexFileData = reshape(hexFileData', 1, []); % Convert to a single row
            %     fileData=getByteStreamFromArray(app.Detections); % convert to byte array
            %     hexFileData = reshape(dec2hex(fileData)', 1, []);% Convert to a single row
            % 
            %     % Create the SQL query
            %     sqlquery = sprintf('INSERT INTO Test (FileID, FileName, FileData) VALUES (%d, ''%s'', 0x%s)', fileID, fileName, hexFileData);
            % 
            %     % Execute the SQL query with error handling
            %     try
            %         exec(app.conn, sqlquery);
            % 
            %     catch ME
            %         fprintf('Error uploading', i, ME.message);
            %     end
            % 
            %     close(app.conn);
            % 
            %     uialert(app.UIFigure, 'Files uploaded successfully', 'Success');
            %     err = false;
            % else
            %     uialert(app.UIFigure, ['Connection failed: ', app.conn.Message], 'Error');
            %     err = true;
            % end
            % 
            % % If upload is successful, disable the upload and enable the select button
            % if ~err
            %     app.MessageTextArea.Value = 'Upload process completed.';
            %     app.ChoosefilestouploadButton.Enable = 'on';
            %     app.UploadfilestodatabaseButton.Enable = 'off';
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileselectionTab = uitab(app.TabGroup);
            app.FileselectionTab.Title = 'File selection';
            app.GridLayout = uigridlayout(app.FileselectionTab);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {''; ''; '     Select .CP1/.CP3 or .FP1/.FP3 files belonging to the same deployment. '; '     Add metadata and upload to repository.'};
            app.ChoosefilestouploadButton = uibutton(app.GridLayout, 'push');
            app.ChoosefilestouploadButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefilestouploadButtonPushed, true);
            app.ChoosefilestouploadButton.Tooltip = {'Click to select .CP1, .CP3, .FP1 and/or .FP3 files to upload.'};
            app.ChoosefilestouploadButton.Text = 'Choose files to upload';
            app.FilesSelectedTextAreaLabel = uilabel(app.GridLayout);
            app.FilesSelectedTextAreaLabel.Text = 'Files Selected';
            app.FilesSelectedTextArea = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea.Editable = 'off';
            app.ConfirmselectionButton = uibutton(app.GridLayout, 'push');
            app.ConfirmselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmselectionButtonPushed, true);
            app.ConfirmselectionButton.Enable = 'off';
            app.ConfirmselectionButton.Tooltip = {'Please select files to upload.'};
            app.ConfirmselectionButton.Text = 'Confirm selection';
            app.MetadatainputTab = uitab(app.TabGroup);
            app.MetadatainputTab.Title = 'Metadata input';
            app.MandatorymetadatainputPanel = uipanel(app.MetadatainputTab);
            app.MandatorymetadatainputPanel.Title = 'Mandatory metadata input';
            app.GridLayout2 = uigridlayout(app.MandatorymetadatainputPanel);
            app.ProjectnameEditFieldLabel = uilabel(app.GridLayout2);
            app.ProjectnameEditFieldLabel.Tooltip = {'Name of the project within the ETN database (RCV_PROJECT)'};
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout2);
            app.LatitudeEditFieldLabel.Tooltip = {'The latitude of the deployment station location in decimal degrees.'};
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.StationnameEditFieldLabel = uilabel(app.GridLayout2);
            app.StationnameEditFieldLabel.Tooltip = {'Name of the deployment station within the ETN database (STATION_NAME). This should refer to a unique location, related to a specific latitude and longitude.'};
            app.StationnameEditFieldLabel.Text = 'Station name';
            app.ProjectnameEditField = uieditfield(app.GridLayout2, 'text');
            app.ProjectnameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectnameEditFieldValueChanged, true);
            app.ProjectnameEditField.Enable = 'off';
            app.ProjectnameEditField.Tooltip = {''};
            app.ProjectnameEditField.Placeholder = 'E.g. Lifewatch';
            app.LatitudeEditField = uieditfield(app.GridLayout2, 'text');
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged2, true);
            app.LatitudeEditField.Enable = 'off';
            app.LatitudeEditField.Tooltip = {''};
            app.LatitudeEditField.Placeholder = 'E.g. 55.2391';
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout2);
            app.LongitudeEditFieldLabel.Tooltip = {'The longitude of the deployment station location in decimal degrees.'};
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.StationnameEditField = uieditfield(app.GridLayout2, 'text');
            app.StationnameEditField.ValueChangedFcn = createCallbackFcn(app, @StationnameEditFieldValueChanged, true);
            app.StationnameEditField.Enable = 'off';
            app.StationnameEditField.Tooltip = {''};
            app.StationnameEditField.Placeholder = 'E.g. bpns-Birkenfels';
            app.LongitudeEditField = uieditfield(app.GridLayout2, 'text');
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged2, true);
            app.LongitudeEditField.Enable = 'off';
            app.LongitudeEditField.Tooltip = {''};
            app.LongitudeEditField.Placeholder = 'E.g. 7.6342';
            app.ReceivernameEditFieldLabel = uilabel(app.GridLayout2);
            app.ReceivernameEditFieldLabel.Tooltip = {'The name of the receiver within the ETN database (RECEIVER). This should be composed of ''Model number''-''Serial number''.'};
            app.ReceivernameEditFieldLabel.Text = 'Receiver name';
            app.DeploymentdatetimeEditFieldLabel = uilabel(app.GridLayout2);
            app.DeploymentdatetimeEditFieldLabel.Tooltip = {'Date and time the receiver was deployed, in 24-hour UTC. Format yyyy-mm-dd hh-MM-ss.'};
            app.DeploymentdatetimeEditFieldLabel.Text = 'Deployment datetime';
            app.DeploymentdatetimeEditField = uieditfield(app.GridLayout2, 'text');
            app.DeploymentdatetimeEditField.ValueChangedFcn = createCallbackFcn(app, @DeploymentdatetimeEditFieldValueChanged, true);
            app.DeploymentdatetimeEditField.Enable = 'off';
            app.DeploymentdatetimeEditField.Tooltip = {''};
            app.DeploymentdatetimeEditField.Placeholder = 'E.g. 2022-06-28 10:05:00';
            app.ReceivernameEditField = uieditfield(app.GridLayout2, 'text');
            app.ReceivernameEditField.ValueChangedFcn = createCallbackFcn(app, @ReceivernameEditFieldValueChanged, true);
            app.ReceivernameEditField.Enable = 'off';
            app.ReceivernameEditField.Tooltip = {''};
            app.ReceivernameEditField.Placeholder = 'E.g. POD-7106';
            app.ActivationdatetimeEditFieldLabel = uilabel(app.GridLayout2);
            app.ActivationdatetimeEditFieldLabel.Tooltip = {'Date and time the receiver was activated, in 24-hour UTC. Format yyyy-mm-dd hh-MM-ss.'};
            app.ActivationdatetimeEditFieldLabel.Text = 'Activation date time';
            app.ActivationdatetimeEditField = uieditfield(app.GridLayout2, 'text');
            app.ActivationdatetimeEditField.ValueChangedFcn = createCallbackFcn(app, @ActivationdatetimeEditFieldValueChanged, true);
            app.ActivationdatetimeEditField.Enable = 'off';
            app.ActivationdatetimeEditField.Tooltip = {''};
            app.ActivationdatetimeEditField.Placeholder = 'E.g. 2022-06-30 00:00:00';
            app.ClassifierButtonGroup = uibuttongroup(app.GridLayout2);
            app.ClassifierButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClassifierButtonGroupSelectionChanged, true);
            app.ClassifierButtonGroup.Tooltip = {'The detection classification algorithm applied in the CPOD.exe or FPOD.exe. For CPODs chooce either KERNO-F or HEL-1.'};
            app.ClassifierButtonGroup.Title = 'Classifier';
            app.KERNOFButton = uiradiobutton(app.ClassifierButtonGroup);
            app.KERNOFButton.Enable = 'off';
            app.KERNOFButton.Text = 'KERNO-F';
            app.KERNOFButton.Value = true;
            app.HEL1Button = uiradiobutton(app.ClassifierButtonGroup);
            app.HEL1Button.Enable = 'off';
            app.HEL1Button.Text = 'HEL1';
            app.GenencButton = uiradiobutton(app.ClassifierButtonGroup);
            app.GenencButton.Enable = 'off';
            app.GenencButton.Text = 'Genenc';
            app.NoDolphinsButton = uiradiobutton(app.ClassifierButtonGroup);
            app.NoDolphinsButton.Enable = 'off';
            app.NoDolphinsButton.Text = 'No Dolphins';
            app.NoSonarButton = uiradiobutton(app.ClassifierButtonGroup);
            app.NoSonarButton.Enable = 'off';
            app.NoSonarButton.Text = 'No Sonar';
            app.NextfileButton = uibutton(app.GridLayout2, 'push');
            app.NextfileButton.ButtonPushedFcn = createCallbackFcn(app, @NextfileButtonPushed, true);
            app.NextfileButton.Enable = 'off';
            app.NextfileButton.Tooltip = {'Enter all mandatory metadata fields'};
            app.NextfileButton.Text = 'Next file';
            app.CurrentfileTextAreaLabel = uilabel(app.GridLayout2);
            app.CurrentfileTextAreaLabel.Tooltip = {'Displays the name of the file the metadata should refer to.'};
            app.CurrentfileTextAreaLabel.Text = 'Current file';
            app.CurrentfileTextArea = uitextarea(app.GridLayout2);
            app.CurrentfileTextArea.Editable = 'off';
            app.OptionalmetadatainputPanel = uipanel(app.MetadatainputTab);
            app.OptionalmetadatainputPanel.Title = 'Optional metadata input';
            app.GridLayout3 = uigridlayout(app.OptionalmetadatainputPanel);
            app.DatavaliduntildatetimeEditFieldLabel = uilabel(app.GridLayout3);
            app.DatavaliduntildatetimeEditFieldLabel.Tooltip = {'The date and time until which valid data was recorded. Format YYYY-MM-DD hh-mm-ss.'};
            app.DatavaliduntildatetimeEditFieldLabel.Text = 'Data valid until datetime';
            app.DatavaliduntildatetimeEditField = uieditfield(app.GridLayout3, 'text');
            app.DatavaliduntildatetimeEditField.ValueChangedFcn = createCallbackFcn(app, @DatavaliduntildatetimeEditFieldValueChanged, true);
            app.DatavaliduntildatetimeEditField.Enable = 'off';
            app.DatavaliduntildatetimeEditField.Tooltip = {''};
            app.DatavaliduntildatetimeEditField.Placeholder = 'E.g. 2022-10-28 22:35:00';
            app.RecoverydatetimeEditFieldLabel = uilabel(app.GridLayout3);
            app.RecoverydatetimeEditFieldLabel.Tooltip = {'The date and time the receiver was recovered. Format YYYY-MM-DD hh-mm-ss.'};
            app.RecoverydatetimeEditFieldLabel.Text = 'Recovery date time';
            app.RecoverydatetimeEditField = uieditfield(app.GridLayout3, 'text');
            app.RecoverydatetimeEditField.ValueChangedFcn = createCallbackFcn(app, @RecoverydatetimeEditFieldValueChanged, true);
            app.RecoverydatetimeEditField.Enable = 'off';
            app.RecoverydatetimeEditField.Tooltip = {''};
            app.RecoverydatetimeEditField.Placeholder = 'E.g. 2022-11-28 12:15:00';
            app.MooringtypeButtonGroup = uibuttongroup(app.GridLayout3);
            app.MooringtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MooringtypeButtonGroupSelectionChanged, true);
            app.MooringtypeButtonGroup.Tooltip = {'Type of mooring to which the receiver is attached'};
            app.MooringtypeButtonGroup.Title = 'Mooring type';
            app.SurfaceBuoyButton = uiradiobutton(app.MooringtypeButtonGroup);
            app.SurfaceBuoyButton.Enable = 'off';
            app.SurfaceBuoyButton.Text = 'Surface - Buoy';
            app.BottommooringButton = uiradiobutton(app.MooringtypeButtonGroup);
            app.BottommooringButton.Enable = 'off';
            app.BottommooringButton.Text = 'Bottom mooring';
            app.BottommooringButton.Value = true;
            app.SurfaceWindturbineButton = uiradiobutton(app.MooringtypeButtonGroup);
            app.SurfaceWindturbineButton.Enable = 'off';
            app.SurfaceWindturbineButton.Text = 'Surface - Wind turbine';
            app.UploadTab = uitab(app.TabGroup);
            app.UploadTab.Title = 'Upload';
            app.UploadfilestodatabaseButton = uibutton(app.UploadTab, 'push');
            app.UploadfilestodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfilesButtonPushed, true);
            app.UploadfilestodatabaseButton.Enable = 'off';
            app.UploadfilestodatabaseButton.Tooltip = {'Click to upload files. This may take a few minutes.'};
            app.UploadfilestodatabaseButton.Text = 'Upload files to database';
            app.MessageTextAreaLabel = uilabel(app.UploadTab);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UploadTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VALKYRIE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end