classdef VALKYRIE < matlab.apps.AppBase
    %VALKYRIE Data aggregation tool for porpoise detection data
    %   The app allows batch upload of C-POD and F-POD data (CP1/3 and
    %   FP1/3 files) as well as metadata input related to each deployment
    %   into the European Tracking Network data repository with further
    %   ingestion into EDITOinfra.
    %   
    %   If agreed to, the input files will be stored in the Marine Data
    %   Archive.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        FileselectionTab           matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        VersionLabel               matlab.ui.control.Label
        FilesSelectedTextArea_2    matlab.ui.control.TextArea
        SelectmetadatafileButton   matlab.ui.control.Button
        ConfirmselectionButton     matlab.ui.control.Button
        FilesSelectedTextArea      matlab.ui.control.TextArea
        SelectCFPODfilesButton     matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        MetadataTab                matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        ProcessfilenamemetadatamatchButton  matlab.ui.control.Button
        ChangefilesButton          matlab.ui.control.Button
        OutputTab                  matlab.ui.container.Tab
        GridLayout3                matlab.ui.container.GridLayout
        BacktoFileselectionButton  matlab.ui.control.Button
        ProcessingMessage          matlab.ui.control.TextArea
        SaveoutputfilesButton      matlab.ui.control.Button
    end

    %============================
    % Properties for storing data
    %============================
    properties (Access = private)
        % In order of where they appear in the code:

        requirePairs            % Used for defining if CP1 or FP1 files are included
        Config           % Content form json configuration file
        minDate          % minimum date for deployments 
        spec             % Metadata specifications
        MandatoryFields  % array of the mandatory metadata fields
        OutputOrder      % array of th eorder of all metadata fields
        MetaRoles        % Column names for metadata validation (datetime and positions)
        DatetimeCols     % Columns in the metadata that holds datetime info
        FactorOptions    % The predefined options for values in the factor columns
        Detections       % detections extraxted from input files
        filesTbl         % Table with info on files used for extracting detections (after filtering based on filenames/metadata match)
        pairedFiles      % Files where there is both a CP1/CP3 or FP1/FP3 file
        UnmatchedFiles   % pod files that did not appear in a file pair
        filtFiles        % filtered filenames used for processing (only one copy from each pair)
        MetadataUITable
        MetaData         % Metadata table
        processList      % List of files to be processed after metadata matching
        ProcessingStatus % Store info about whether a file successfully processed
        MetadataOverviewTable matlab.ui.control.Table % Used to display metadata table in app
        OverviewTable     matlab.ui.control.Table
        LogFilePath     % path to current sessions diary log file (error log)
    end
    
    methods (Access = private)
        function chosen = chooseReceiverProjectPopup(app, projects, tbl)
            % Dialogue window where user choses the project number to process files for
            % It will block the app until OK/Cancel are chosen
            % Returns chosen project (string) or "" if cancelled

            projects = string(projects);

            % Compute counts per project (treat missing as missing)
            proj = strtrim(string(tbl.RCV_PROJECT));
            proj(strlength(proj)==0 | ismissing(proj) | strcmpi(proj,"nan")) = missing;
            counts = arrayfun(@(p) sum(proj == p), projects);

            labels = compose("%s  (n=%d)", projects, counts);

            chosen = "";

            d = uifigure('Name','Select Receiver Project', 'WindowStyle','modal', ...
                'Position', [300 300 520 210]);

            msg = sprintf("Metadata contains %d Receiver Projects.\nSelect ONE to keep. All other rows will be removed.", numel(projects));
            uilabel(d, 'Position', [20 145 480 50], 'Text', msg, 'WordWrap', 'on');

            dd = uidropdown(d, ...
                'Position', [20 115 480 28], ...
                'Items', cellstr(labels), ...
                'ItemsData', cellstr(projects), ... % maps selection to raw value
                'Value', cellstr(projects(1)));

            uibutton(d, 'Text', 'OK', ...
                'Position', [320 20 180 40], ...
                'ButtonPushedFcn', @(~,~) onOK());

            uibutton(d, 'Text', 'Cancel', ...
                'Position', [20 20 180 40], ...
                'ButtonPushedFcn', @(~,~) onCancel());

            uiwait(d);

            function onOK()
                chosen = string(dd.Value); % direct project value
                uiresume(d); delete(d);
            end

            function onCancel()
                chosen = "";
                uiresume(d); delete(d);
            end

        end


        function tbl2 = filterByReceiverProject(app, tbl, chosenProject)
            chosenProject = strtrim(string(chosenProject));

            proj = strtrim(string(tbl.RCV_PROJECT));
            proj(strlength(proj)==0 | ismissing(proj) | strcmpi(proj,"nan")) = missing;

            keepIdx = (proj == chosenProject);
            tbl2 = tbl(keepIdx, :);

            if height(tbl2) == 0
                error("Filtering produced 0 rows. Chosen project not found?");
            end
        end

        function cleanupOldLogs(app,logFolder, keepN)
            %CLEANUPOLDLOGS Delete oldest log files beyond the most recent keepN.
            files = dir(fullfile(logFolder, 'VALKYRIE_session_*.txt'));
            if numel(files) <= keepN
                return;
            end
            [~, idx] = sort([files.datenum], 'descend');
            files = files(idx);
            toDelete = files(keepN+1:end);
            for k = 1:numel(toDelete)
                try
                    delete(fullfile(toDelete(k).folder, toDelete(k).name));
                catch
                    % non-critical, skip if a file can't be removed
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, ~)
            try
                fprintf('=== Session ended normally: %s ===\n', char(datetime('now')));
                diary off;
            catch
                % ignore - don't block closing over a logging issue
            end
            delete(app);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Start by setting up the error log for the session (Matlab
            % calls it diary)
            try
                if ispc
                    logRoot = fullfile(getenv('APPDATA'), 'VALKYRIE', 'logs');
                else
                    logRoot = fullfile(char(java.lang.System.getProperty('user.home')),'.valkyrie','logs');
                end

                if ~exist(logRoot, 'dir')
                    mkdir(logRoot);
                end

                timestamp = char(datetime('now', 'Format', 'yyyy-MM-dd_HHmmss'));
                app.LogFilePath = fullfile(logRoot, ['VALKYRIE_session_',timestamp,'.txt']);

                app.TextArea.Value = [app.TextArea.Value; ...
                    {''}; ...
                    {'Troubleshooting: if something goes wrong, a session log is saved here:'}; ...
                    {app.LogFilePath}];

                diary(app.LogFilePath);
                diary on;

                fprintf('===================================================\n');
                fprintf('VALKYRIE session started: %s\n', char(datetime('now')));
                fprintf('MATLAB version: %s\n', version);
                fprintf('Deployed (compiled): %d\n', isdeployed);
                fprintf('===================================================\n');

                % Keep only the most recent 20 log files so the folder doesn't
                % grow forever over many sessions
                app.cleanupOldLogs(logRoot, 20);

            catch loggingME
                % If logging itself fails (maybe because writing
                % permission) it wont stop the app from running, but
                % instead give a warning
                warning('Could not initialize the session log: %s', loggingME.message);
            end


            % =============================================================
            % Everything below is REQUIRED for the app to work. If any of it
            % fails the app must not pretend to be usable, so it is wrapped
            % in try/catch and the workflow buttons are disabled on failure.
            % =============================================================
            try

                % ---- Hook up close behaviour so the diary gets flushed cleanly ----
                app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);

                % Add paths to folder with configuration files, helper
                % scripts and graphic content
                basePath = fileparts(mfilename('fullpath'));
                foldersToAdd = {'helpers', 'config', 'graphics'};

                for i = 1:numel(foldersToAdd)
                    folderPath = fullfile(basePath, foldersToAdd{i});
                    if isfolder(folderPath)
                        addpath(genpath(folderPath));
                    else
                        % Logged, not fatal: in the compiled app these files
                        % are already on the path inside the CTF archive.
                        fprintf('Folder not found (may be normal when deployed): %s\n', folderPath);
                    end
                end

                % ---- Version label ------------------------------------------------
                % Cosmetic only. A missing valkyrieVersion.m means the build did
                % not package it (it must live in app_source/), which is worth a
                % loud line in the log but must not abort startup.
                try
                    versionStr = "v" + string(valkyrieVersion());
                catch versionME
                    versionStr = "v?";
                    fprintf(2, ['WARNING: valkyrieVersion() unavailable (%s). ' ...
                        'The build is probably missing app_source/valkyrieVersion.m.\n'], ...
                        versionME.message);
                end
                app.VersionLabel.Text = versionStr;
                app.UIFigure.Name = "VALKYRIE " + versionStr;

                fprintf('VALKYRIE version: %s\n', versionStr);
                fprintf('App base path: %s\n', basePath);
                app.TextArea.Value = [app.TextArea.Value; {''}; {char("Version: " + versionStr)}];

                app.requirePairs = 1; % if = 1: will check if both C/FP1 and C/FP3 files are included

                % ---- Load and validate the .json configuration file ---------------
                cfg = fullfile(basePath,'config','metadata_validation.json');
                if ~isfile(cfg)
                    error('VALKYRIE:startup:configMissing', ...
                        'The configuration file could not be found:\n%s', cfg);
                end

                configText = fileread(cfg);

                try
                    app.Config = jsondecode(configText);
                catch jsonME
                    error('VALKYRIE:startup:configUnreadable', ...
                        'The configuration file could not be read as JSON:\n%s\n\n%s', ...
                        cfg, jsonME.message);
                end

                % Guard: everything downstream dot-indexes app.Config. If it is
                % not a struct with the expected fields, say so HERE rather than
                % letting it fail later as "Dot indexing is not supported...".
                requiredCfgFields = {'MinDate','MetadataSpec','MandatoryFields','OutputOrder'};
                if ~isstruct(app.Config) || ~isscalar(app.Config)
                    error('VALKYRIE:startup:configInvalid', ...
                        'The configuration file did not produce a valid settings structure:\n%s', cfg);
                end
                missingCfg = requiredCfgFields(~isfield(app.Config, requiredCfgFields));
                if ~isempty(missingCfg)
                    error('VALKYRIE:startup:configIncomplete', ...
                        'The configuration file is missing required entries: %s\n(%s)', ...
                        strjoin(missingCfg, ', '), cfg);
                end
                if ~isstruct(app.Config.MetadataSpec) || isempty(fieldnames(app.Config.MetadataSpec))
                    error('VALKYRIE:startup:specInvalid', ...
                        'MetadataSpec in the configuration file is empty or malformed:\n%s', cfg);
                end

                % Specify the minimum expected date for detection data
                % Can be changed in the configuration file
                app.minDate = datetime(app.Config.MinDate, 'TimeZone', 'UTC');

                % mandatory field names for metadata and the data type - it is a structure where each field is the column names and each has a 1x1 struct with the field type (and options if field type is Predefined)
                app.spec = app.Config.MetadataSpec;

                % Get the fields that are mandatory to have in the metadata
                app.MandatoryFields = app.Config.MandatoryFields;

                % get the correct order of the columns for metadata (has to be
                % exactly the same for ETN to ingest)
                app.OutputOrder = app.Config.OutputOrder;

                % Specify which fields are used for metadata validation
                roles = struct(); % to hold metadata with the Roles identifier
                dtCols = strings(0,1); % to hold metadata with type Datetime
                % Specify the metadata that has the field Options
                fo = struct(); % factor options
                fields = fieldnames(app.spec); %get field names
                for f = 1:numel(fields)
                    fname = fields{f};
                    entry = app.spec.(fname); % get one entry in the metadata spec struct
                    if ~isstruct(entry)
                        error('VALKYRIE:startup:specEntryInvalid', ...
                            'MetadataSpec entry "%s" is not a settings block.', fname);
                    end
                    if isfield(entry,'Role') % find the ones where Role is included
                        roles.(entry.Role) = fname; % Get the column name of the ones where Role is included
                    end
                    if isfield(entry, 'Type') && strcmpi(string(entry.Type),"Datetime") % get the entries where Type is included and where Type is Datetime
                        dtCols(end+1,1) = upper(string(fname)); %#ok<AGROW> % Get the column name of each
                    end
                    if isfield(entry, "Options")
                        % Store as UPPER fieldname for easy matching later
                        fo.(upper(fname)) = string(entry.Options);
                    end
                end

                % Guard: validateMetadata dot-indexes these four roles. Missing
                % ones would otherwise fail as "Dot indexing is not supported..."
                requiredRoles = {'DeployDate','ActivationDate','RecoverDate','ValidUntilDate'};
                missingRoles = requiredRoles(~isfield(roles, requiredRoles));
                if ~isempty(missingRoles)
                    error('VALKYRIE:startup:rolesIncomplete', ...
                        'The configuration file does not define the Role(s): %s\n(%s)', ...
                        strjoin(missingRoles, ', '), cfg);
                end

                app.MetaRoles = roles; %metadata with the Roles identifier
                app.DatetimeCols = dtCols; %metadata with type Datetime
                app.FactorOptions = fo;      % metadata factor options

                % Prepare empty table for detections
                app.Detections = table();

                fprintf('Configuration loaded from: %s\n', cfg);
                disp('Loaded roles:'); % DEBUGGING
                disp(app.MetaRoles); % DEBUGGING
                disp('Output order:'); %DEBUGGING
                disp(app.OutputOrder); %DEBUGGING
                disp('Mandatory fields:'); %DEBUGGING
                disp(app.MandatoryFields); %DEBUGGING

                % Below is purely visual:
                % --- Add a panel that covers only the tab headers to make it
                % prettier
                headerCover = uipanel(app.UIFigure, ...
                    'BackgroundColor',[0.94 0.94 0.94], ... % same as UIFigure default gray
                    'BorderType','none', ...
                    'Units','pixels', ...
                    'Position',[app.TabGroup.Position(1) ...
                                app.TabGroup.Position(2)+app.TabGroup.Position(4)-25 ...
                                app.TabGroup.Position(3) ...
                                25]);

                % Optional: bring the cover panel to the top
                uistack(headerCover,'top');

            catch startupME
                % ---- Fail loudly, and stop the user from getting further ----
                fprintf(2, '--- STARTUP FAILED at %s ---\n%s\n', ...
                    char(datetime('now')), getReport(startupME, 'extended', 'hyperlinks', 'off'));

                app.SelectCFPODfilesButton.Enable   = 'off';
                app.SelectmetadatafileButton.Enable = 'off';
                app.ConfirmselectionButton.Enable   = 'off';

                logInfo = "";
                if ~isempty(app.LogFilePath)
                    logInfo = newline + newline + "Details were written to the session log:" + ...
                        newline + string(app.LogFilePath);
                end

                uialert(app.UIFigure, ...
                    "VALKYRIE could not start up correctly and cannot process files." + ...
                    newline + newline + string(startupME.message) + ...
                    newline + newline + "Please report this together with the session log." + logInfo, ...
                    'Startup failed', 'Icon', 'error');
            end
        end

        % Button pushed function: SelectCFPODfilesButton
        function SelectCFPODfilesButtonPushed(app, event)
            % =====================
            % |POD file selection |
            % =====================
            % The user is able to select files individually or all files in
            % a directory (folder and subfolders) that have the extension
            % of pod files. It is checked that each pod-file pair has been
            % uploaded - if not an error will be presented to the user.

            % TO DO: set a limit of files that can be uploaded at the same
            % time

            validExt = {'.CP1', '.CP3', '.FP1', '.FP3'};

            % Disable button while working so it cannot be pressed twice
            app.SelectCFPODfilesButton.Enable = 'off'; 

            % Function where user can upload either single or multiple
            % files. Output is the path to the parent folder and the
            % filePaths to each specific file.
            [filePaths, ~] = fileSelect(validExt);

            % If user cancelled the file dialog, re-enable and return
            if isempty(filePaths)
                app.SelectCFPODfilesButton.Enable = 'on';
                return;
            end

            % --- Notify user that background processing has started ---
            app.FilesSelectedTextArea.Value = 'Reading files, please wait...';
            drawnow;

            % Create table with filepath, filename and file size info:
            app.filesTbl = createFileTable(filePaths);
            nTotalFiles = height(app.filesTbl);
   
             % Check that all file pairs are complete and only POD files are
            % selected - if not give user an error message
            app.FilesSelectedTextArea.Value = 'Checking file pairs...';
            drawnow;

            % Check that all file pairs are complete and only POD files are
            % selected - if not give user a error message
            [~, ~, ~, app.UnmatchedFiles, app.pairedFiles] = checkFileExtension(cellstr(app.filesTbl.FullPath), app.requirePairs); % app.requirePairs == 1 means that the function will evaluate if all pod pairs are complete
            nUnpaired = height(app.UnmatchedFiles);
        
             % Check for duplicate files with different names
            app.FilesSelectedTextArea.Value = 'Checking for duplicates...';
            drawnow;
            % Check for dupliate files with different names
            [app.filesTbl,removedFiles,stationConflicts] = removeFileDuplicates(app.filesTbl, app.pairedFiles);
            nRemovedFiles = height(removedFiles);

            % --- Processing done: show the file list ---
            app.FilesSelectedTextArea.Value = app.filesTbl.NameExt;

            % --- Warn about recordings appearing under different station names ---
            % This requires user attention before processing continues, so it
            % is shown as a blocking alert directly after deduplication.
            if ~isempty(stationConflicts)
                conflictList = strjoin("  " + stationConflicts, newline);
                conflictMsg  = "The following recordings have same date and POD ID and may be duplicates. Filenames that match station name" + ...
                               "in metadata will be used. Please check your files before continuing:" + ...
                               newline + newline + conflictList;
                uialert(app.UIFigure, conflictMsg, 'Possible Duplicate Recordings', ...
                    'Icon', 'warning');
                % uialert is modal — execution pauses here until the user clicks OK
            end

            disp("These are the files that will be processed:") %DEBUGGING
            disp(app.filesTbl.NameExt); %DEBUGGING

            % If not all files are present display a box to the user where
            % they can chose to change files or continue with the file
            % pairs that are there.
            if ~isempty(app.UnmatchedFiles)
                msg = sprintf(['Some files appear to be duplicates or do not have a matching pair.\n' ...
                               'Duplicates/unmatched files: %d\n\n' ...
                               'Do you want to continue with the %d complete pairs?'], ...
                               nUnpaired + nRemovedFiles, nTotalFiles - (nUnpaired + nRemovedFiles));
            
                choice = uiconfirm(app.UIFigure, msg, 'Unmatched file pairs', ...
                    'Options', {'Continue with unique file pairs','Cancel'}, ...
                    'DefaultOption', 'Continue with unique file pairs', ...
                    'CancelOption', 'Cancel');
            
                if strcmp(choice, 'Cancel')
                    return;
                end


            end

            % Filter for metadata entry (.CP3 or .FP3) - basically just
            % takes away the CP1/FP1 copy of a filename and keeps a single
            % file per file pair
            isP3 = endsWith(app.filesTbl.NameExt,'.CP3','IgnoreCase',true) | ...
                endsWith(app.filesTbl.NameExt,'.FP3','IgnoreCase',true);
            app.filtFiles = app.filesTbl.NameExt(isP3);

            app.SelectCFPODfilesButton.Enable = 'on';

            %if there is no metadata yet
            if isempty(app.MetaData) 
                app.SelectmetadatafileButton.Enable = 'on';
            end

            %if there is already metadata
            if ~isempty(app.MetaData)               
                app.ConfirmselectionButton.Enable = 'on';
            end


        end

        % Button pushed function: SelectmetadatafileButton
        function SelectmetadatafileButtonPushed(app, event)
            % ===============================
            % Metadata CSV file selection
            % ===============================
            % The user can select a single metadata file. 

            % The metadata checks are:
            % 1. If all mandatory fields are there and are non-empty
            % 2. Check that all the non-mandatory columns are there and add
            % those that are missing.
            % 3. Check that the order of the columns is correct, or enforce
            % the mandatory output order
            % 4. Check that the non-mandatory columns have NaN (cannot be
            % empty)

            % The metadata validation steps are:
            % 1. Check that there is only one project name (can only upload
            % a single project at a time)
            % 2. Check the datetime formats and enforce the correct format:
            % "yyyy-MM-dd'T'HH:mm:ss'Z'"
            % 3. Check that the coordinates are numeric and are within
            % logical bounds
            % 4. Check that the predefined factor options are correct
            % <<<<TO DO>>>>

            
            app.SelectmetadatafileButton.Enable = 'off';

            % User can select a single metadata file
            [file, path] = uigetfile('*.csv', 'Select Metadata File');


            % If user cancels the dialog
            if isequal(file, 0)
                app.SelectmetadatafileButton.Enable = 'on';
                return;
            end


            % Display filename
            app.FilesSelectedTextArea_2.Value = {file};
              
            try
                
                tbl = loadMetadataFile(fullfile(path, file)); % load metadata file - will give error if it fails
                
                tbl = createDateTime(tbl, app.Config); % Takes year, month, date columns and collapses them + gives ISO 8601 format
 
                disp("Metadata was loaded and the timestamps formatted to ISO 8601:") %DEBUGGING
                disp(tbl); %DEBUGGING
                
                [tbl, all_identical, projects] = checkMetadataColumns(tbl, app.MandatoryFields, app.OutputOrder,app.DatetimeCols); % checks for recquired columns in metadata - will give error if it fails. Will add optional columns with no data if missing (NaN for string and empty for datetime)
                
                app.MetadataUITable = tbl;
                

                % Display the possible Receiver Projects to user for them
                % to chose one:
                if ~all_identical
                    chosen = app.chooseReceiverProjectPopup(projects, tbl);
            
                    if chosen == ""
                        % User cancelled: keep Next disabled and ask them to re-upload/fix
                        uialert(app.UIFigure, ...
                            "You cancelled Receiver Project selection. Please upload metadata again or correct the file.", ...
                            "Selection cancelled");
                        return
                    end
            
                    % Filter metadata to chosen project
                    tbl = app.filterByReceiverProject(tbl, chosen);
                end

                disp("The metadata was filtered to include a single project:") %DEBUGGING
                disp(tbl); %DEBUGGING

                % Check that datetime and coordinates are in correct format
                tbl = validateMetadata(tbl,app.minDate,app.MetaRoles,app.DatetimeCols);  % Uses validateDatetime_amb & validateCoordinates functions
                disp("The validation worked! Here's the metadata table.") %DEBUGGING
                disp(tbl); %DEBUGGING

                % Find all PODdata that has matching metadata and update
                % metadata file with match info
                % UPDATE: this match has to be done based on the timestamp
                % and pod id in the filename instead of having filename in
                % the metadata
                % TO DO: filter files if there are duplicates. If there is
                % a match of more than a single file pair and these match
                % in size, keep only one pair.
                tbl = matchMetadataWithPOD(app.filesTbl.NameExt,tbl);
                disp('The match worked! Here is the metadata table.') %DEBUGGING
                disp(tbl);

                app.MetaData = tbl;                

                app.ConfirmselectionButton.Enable = 'on';
            catch ME
                fprintf(2, '--- ERROR at %s in SelectmetadatafileButton ---\n%s\n', ...
                    char(datetime('now')), getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ME.message, 'Metadata Error.');
            end

            app.SelectmetadatafileButton.Enable = 'on';
        end

        % Button pushed function: ConfirmselectionButton
        function ConfirmselectionButtonPushed(app, event)
            % ===================================================
            % When file selection has been confirmed... 
            % ===================================================
            % The user will be presented with a table of metadata. It is
            % color coded so matched metadata will have green rows and
            % unmatched metadata will have red rows. 
            % Two buttons at the bottom - one to process matched files and
            % one to go back (cancel) and upload other files and hope for a
            % better match. 

            % Switch to the metadata tab
            app.TabGroup.SelectedTab = app.MetadataTab;

            % Create the metadata overview to view matched metadata - if it
            % doesn't already exist
            if isempty(app.MetadataOverviewTable) || ~isvalid(app.MetadataOverviewTable)
                app.MetadataOverviewTable = uitable(app.GridLayout2);
                app.MetadataOverviewTable.Layout.Row = 1;      % top row (the big area you set to 450 px)
                app.MetadataOverviewTable.Layout.Column = [1 15];  % span full width
                app.MetadataOverviewTable.ColumnSortable = true;
                app.MetadataOverviewTable.FontSize = 14;
            end


           % ---------- Build a readable MatchingFiles column ----------
            % Replace the column with newline-separated string for display
            % while keeping unmatched cases readable.
            if ismember('MatchingFiles', app.MetaData.Properties.VariableNames)
                mf = app.MetaData.MatchingFiles;  % cell array, each cell: cellstr of filenames
                mfDisplay = strings(height(app.MetaData), 1);
        
                hasFileNameCol = ismember('FileName', app.MetaData.Properties.VariableNames);
        
                for r = 1:height(app.MetaData)
                    if ~isempty(mf{r})
                        % Join filenames with newline
                        mfDisplay(r) = strjoin(string(mf{r}), newline);
                    else
                        % Unmatched rows: show informative text if we have FileName (for file-only rows)
                        if hasFileNameCol && strlength(app.MetaData.FileName(r)) > 0
                            mfDisplay(r) = "Unmatched file: " + string(app.MetaData.FileName(r));
                        else
                            mfDisplay(r) = "No matching files";
                        end
                    end
                end
        
                % Save column for UI
                app.MetaData.MatchingFilesDisplay = mfDisplay;
                %Store the raw matching files for later
                app.MetaData.MatchingfilesRaw = app.MetaData.MatchingFiles ;

                % Replace MatchingFiles column with the display strings
                app.MetaData.MatchingFiles = mfDisplay;
            end


            % ---------- Push data to the table ----------
            
            % Show in table
            app.MetadataOverviewTable.Data = app.MetaData;

            % Make columns auto-size (so long lists are visible)
            app.MetadataOverviewTable.ColumnWidth = 'auto';
        
            % Optional: remove previous styles (if you have a helper for that)
            try
                removeStyle(app.MetadataOverviewTable);
            catch
                % ignore if function not available
            end


            % ---------- Apply row coloring ----------
            % Matched rows: MatchingFiles not equal to "No matching files" (string)
            % Unmatched: "No matching files" or starts with "Unmatched file:"

            % Find rows with matches based on the MatchingFiles not being
            % empty
            hasMatch = app.MetaData.MatchCount > 0;
            iY = find(hasMatch);  % Non-empty cell = match
            iN = find(~hasMatch);   % Empty cell = no match

            % Apply the styles
            sY = uistyle('BackgroundColor',[0.85 1.00 0.85]);
            sN = uistyle('BackgroundColor',[1.00 0.85 0.85]);
            if ~isempty(iY), addStyle(app.MetadataOverviewTable,sY,'row',iY); end
            if ~isempty(iN), addStyle(app.MetadataOverviewTable,sN,'row',iN); end

            % Disable buttons
            app.SelectCFPODfilesButton.Enable = 'off';
            app.ConfirmselectionButton.Enable = 'off';
            app.SelectmetadatafileButton.Enable = 'off';

            % Enable buttons
            app.ChangefilesButton.Enable = 'on';
            app.ProcessfilenamemetadatamatchButton.Enable = 'on';
           
        end

        % Button pushed function: ChangefilesButton
        function ChangefilesButtonPushed(app, event)
            %Function to allow user to go back and upload different
            %podfiles and/or metadata file, CHOOSING WHICH ONE.

            choice = uiconfirm(app.UIFigure,...
                'Which files would you like to change?',...
                'Change Files',...
                'Options',{'Metadata','POD Files','Both','Cancel'},...
                'DefaultOption',1,...
                'CancelOption',4);

            switch choice

                case 'Metadata'

                    % Go back to file selection tab
                    app.TabGroup.SelectedTab = app.FileselectionTab;

                    % Allow only metadata selection
                    app.SelectmetadatafileButton.Enable = 'on';

                    % Clear metadata display
                    if ~isempty(app.MetadataOverviewTable) && isvalid(app.MetadataOverviewTable)
                        app.MetadataOverviewTable.Data = table();
                    end


                case 'POD Files'

                    app.TabGroup.SelectedTab = app.FileselectionTab;

                    % Allow CFPOD files to be reselected
                    app.SelectCFPODfilesButton.Enable = 'on';


                case 'Both'

                    app.TabGroup.SelectedTab = app.FileselectionTab;

                    app.SelectCFPODfilesButton.Enable = 'on';
                    app.SelectmetadatafileButton.Enable = 'on';

                    if ~isempty(app.MetadataOverviewTable) && isvalid(app.MetadataOverviewTable)
                        app.MetadataOverviewTable.Data = table();
                    end

                case 'Cancel'
                    return
            end

            app.ChangefilesButton.Enable = 'off';
            app.ProcessfilenamemetadatamatchButton.Enable = 'off';

        end

        % Button pushed function: ProcessfilenamemetadatamatchButton
        function ProcessfilenamemetadatamatchButtonPushed(app, event)
            
            % Function to begin process to extract detections and create
            % detection output and metadata output for upload.


            % =======================
            % Extract detections
            % =======================

            % For each uploaded pod file pair the detections are extracted
            % and put into the format recquired for upload into the ETN
            % database.

            % Make the csv with the information on the receivers used -
            % also in a format accepted for upload to ETN

            % Make an overview table that can be viewde (but optionally
            % saved). This will show the files processed as well as the
            % duration between deployment and data valid until and the
            % duration between the min and max datetimes for each file in
            % the extracted detections data. This will allow the user to
            % get an overview of the data and whether there are any issues
            % between metadata and detection files.

            % ===========================================================
            
            % Disable Change Files button
            app.ChangefilesButton.Enable = 'off';
            app.ProcessfilenamemetadatamatchButton.Enable = 'off'; % As soon as you push it you should not be able to push it again

            disp(app.MetaData.MatchingFiles); %DEBUG

            [processTbl,metaForProcess,app.processList] = buildProcessTbl(app.MetaData, app.filtFiles,app.filesTbl);

            disp(processTbl); %DEBUG
            disp(metaForProcess); %DEBUG

            % If nothing to process:
            if numel(app.processList) == 0
                uialert(app.UIFigure, ...
                    'No files with matching metadata were found. Please review the metadata and file selection.', ...
                    'No Matched Files');
                return;
            end

            %================
            % Import POD data
            %================

            % For each file pair:
            % % - Import data from both XP1 and XP3 file
            % % - Run summarising/formatting function depending on file
            % type

            % Create progress dialogue
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Processing Files', ...
                'Message', 'Starting...', ...
                'Indeterminate', 'off');

            % Set up timer for progress messages
            nFiles = height(processTbl);
            tic; % start timer

            % Specififies if the nall will be calculated (only if C/FP1
            % files also uploaded.
            n = '';
            if app.requirePairs == 1
                n = '-n';
            end
            
            % Preallocate status array
            app.ProcessingStatus = repmat({'NOT ATTEMPTED'}, nFiles, 1) ;

            % Create a combined table with detections - identifiable by the
            % filename:
            for i = 1:height(processTbl)

                % Update progress
                d.Value = (i-1) / nFiles;
                elapsed = toc;
                avgPerFile = elapsed / max(1, i-1); % average time so far (skip i=1)
                estRemain = avgPerFile * (nFiles - (i-1)); 

                if i > 1
                    timeMsg = sprintf(' (est. %s left)', string(seconds(estRemain), "hh:mm:ss"));
                else
                    timeMsg = '';
                end

                d.Message = [sprintf('Processing file %d of %d...', i, nFiles) timeMsg];

                drawnow; %ensure UI updates

                % get filename and extension
                filename = processTbl.FileName{i};
                path = processTbl.Path{i};
                pairPath = processTbl.PairPath{i};
                [~,~,ext] = fileparts(filename);
                
                % DEBUGGING 
                 disp(path)
                 disp(filename)
                 disp([path,'\', filename])

                % ====================================
                % Read files and summarising functions
                % ====================================

                % % extract and format files depending on their extension
                try % Wrapped in a try-catch to ensure app will not crash if the import and formatting doesn't work for one of the files
                    switch ext
                        case '.CP3'
                            % Import CP3 (and CP1) file
                            tmpMinutes = CP3read_DTO(path,filename,n,pairPath); %DEBUG version of CP3read
                        case '.FP3'
                            % Import FP3 (and FP1) file
                            % format
                            tmpMinutes = FP3read_DTO(path,filename, n, pairPath);
                    end

                    % Validate tmpMinutes before conversion to ETN
                    if isempty(tmpMinutes) || isscalar(tmpMinutes)
                        error('NoData:EmptyFile', 'FILE CONTAINS NO DATA');
                    end

                    % Convert imported detections to match ETN format
                    ETN = CP3read_2_etn(tmpMinutes);

                    % Mark as successfully processed
                    app.ProcessingStatus{i} = 'PROCESSED' ;

                catch ME 
                    app.ProcessingStatus{i} = ME.message;
                    continue; % SKip to next file
                end
                
                disp(app.ProcessingStatus) % DEBUGGING

                % Filter detection data before appending - from deployment
                % to valid data until:
        
                % filter ETN to deployment window using aligned metadata row i
                % the metaIdx will look up what row of metadata corresponds
                % to the file currently being processed - this is
                % especially relevant for deployments where more than a
                % single POD file is generated for a single deployment
                metaIdx = processTbl.MetaRow(i);
                disp(metaIdx); %DEBUG

                disp(app.MetaData.DeploymentID); %DEBUG

                % Move filename to PODfile (last column that will be good
                % for user to have but deleted once going into ETN):
                ETN.PODfile = string(ETN.filename);
                disp(height(ETN.PODfile)); %DEBUG

                % Change the filename to the unique identifier for the deployment:
                depID = string(app.MetaData.DeploymentID(metaIdx));
                ETN = convertvars(ETN, "filename", "string"); %ensure the rigth format for overwriting with depID

                disp(depID); %DEBUG
                ETN.filename(:) = depID;

                dep = app.MetaData.DEPLOY_DATE_TIME(metaIdx);
                val = app.MetaData.VALID_DATA_UNTIL_DATE_TIME(metaIdx);

                ETN.DETECTION_DATE_TIME.TimeZone = 'UTC'; % ensure same timezone as metadata

                % Find rows that have time in range of deploy and valid
                % data
                inRange = ETN.DETECTION_DATE_TIME >= dep & ETN.DETECTION_DATE_TIME <= val;
                ETN = ETN(inRange, :);

                % Append detection data
                if isempty(app.Detections)
                    app.Detections = ETN;
                else
                    app.Detections = [app.Detections; ETN];
                end
            end

            disp(app.Detections.Properties.VariableNames) % DEBUGGING


            % Update message for completed file
            d.Value = 1;
            d.Message = sprintf('File %d of %d completed%s', nFiles, nFiles, timeMsg);
            drawnow;

            % Close progress dialogue
            close(d);

            
            % Switch to the output tab
            app.TabGroup.SelectedTab = app.OutputTab;

            % === Build processed files overview ===
           % After app.Detections has been built
            overview = makeFileOverview(cellstr(app.processList), app.MetaData, app.Detections, app.ProcessingStatus);
            
            % Show in a table on OutputTab
            if isempty(app.OverviewTable) || ~isvalid(app.OverviewTable)
                app.OverviewTable = uitable(app.GridLayout3, 'Data', overview);
                app.OverviewTable.Layout.Row = [3 11];   % span more rows so it’s big
                app.OverviewTable.Layout.Column = [2 14];
                app.OverviewTable.ColumnSortable = true;
                app.OverviewTable.FontSize = 13;
            else
                app.OverviewTable.Data = overview;
            end

            app.ProcessingMessage.Value = { ...
            'Processing detection files was successful.' ...
            'Here is an overview of the deployment length based on the metadata input and the datetime in detection files.' ...
            'Click to save a file with Receiver information, Metadata and a combined detections output.'};


            % Enable Save Output button
            app.SaveoutputfilesButton.Enable = 'on';


        end

        % Button pushed function: SaveoutputfilesButton
        function SaveoutputfilesButtonPushed(app, event)

            app.SaveoutputfilesButton.Enable = 'off'; % Turn button off as it is pressed

            % Save cleaned metadata
            % Ask user for base filename and folder
            [baseFile, basePath] = uiputfile('*.csv', 'Save output files as');

            % If user cancels the dialog
            if isequal(baseFile, 0)
                app.SaveoutputfilesButton.Enable = 'on'; % Re-enable if cancelled
                return;
            end

            % Remove .csv extension if present
            [~, nameOnly] = fileparts(baseFile);

            %Grey out status area and show progress while files are being
            %saved
            app.OverviewTable.Enable = 'off';
            app.ProcessingMessage.Value = 'Saving files...';
            drawnow;  % force UI update before the save begins


            try
                % Save cleaned metadata
                % Keep only metadata rows (exclude file-only) AND with at least one match
                rowsMeta = ismember(app.MetaData.RowType, "metadata"); % has metadata
                rowsHasMatch = app.MetaData.MatchCount > 0; % has match
                cleanedMeta = app.MetaData(rowsMeta & rowsHasMatch, :);

                % Filter to only include columns required for ETN:
                cleanedMeta = cleanedMeta(:, app.OutputOrder);

                % Remove NaTs and leave them blank
                C = table2cell(cleanedMeta);
                isNaTcell = cellfun(@(x) isa(x,'datetime') && isnat(x), C); % Replace only NaT (datetime missing)
                C(isNaTcell) = {''};
                cleanedMeta = cell2table(C, 'VariableNames', cleanedMeta.Properties.VariableNames); % Convert back to table

                app.ProcessingMessage.Value = 'Saving deployment file...';
                drawnow;  % force UI update before the save begins

                % Save the cleaned and matched metadata (called deployments, to
                % match ETN formatting)
                writetable(cleanedMeta, fullfile(basePath, [nameOnly '_deployments.csv']));

                app.ProcessingMessage.Value = 'Saving receivers file...';
                drawnow;  % force UI update before the save begins

                % Create receiver file
                Receivers = createReceivers(app.MetaData, cellstr(app.processList));
                % Save receiver file
                writetable(Receivers, fullfile(basePath, [nameOnly '_receivers.csv']));

                % ---- Detections file, now split one-per-deployment ----
                app.ProcessingMessage.Value = 'Splitting detections by deployment...';
                drawnow;

                splitCol = 'filename'; % 

                if ~ismember(splitCol, app.Detections.Properties.VariableNames)
                    error('Expected column "%s" not found in detections table. Available columns: %s', ...
                        splitCol, strjoin(app.Detections.Properties.VariableNames, ', '));
                end

                detOutFolder = fullfile(basePath, [nameOnly '_detections']);
                if ~exist(detOutFolder, 'dir')
                    mkdir(detOutFolder);
                end

                savedNames = splitTableByColumn(app.Detections, splitCol, detOutFolder, [nameOnly '_detections_'], app);
                nGroups = numel(savedNames);

                app.OverviewTable.Enable = 'on';

                 % ---- Success message ----
                 msg = sprintf(['All files saved successfully to:\n%s\n\n' ...
                     '  - %s_deployments.csv\n' ...
                     '  - %s_receivers.csv\n' ...
                     '  - %d detection file(s) in %s_detections\\ (one per deployment)'], ...
                     basePath, nameOnly, nameOnly, nGroups, nameOnly);
                 uialert(app.UIFigure, msg, 'Files Saved', 'Icon', 'success');

                % --- Enable the Reset button so the user can start a new session ---
                app.BacktoFileselectionButton.Visible = 'on';
                app.BacktoFileselectionButton.Enable = 'on';
            catch ME
                fprintf(2, '--- ERROR at %s in SaveoutputfilesButtonPushed ---\n%s\n', ...
                    datestr("now"), getReport(ME, 'extended', 'hyperlinks', 'off'));
                % Restore text area and re-enable save button so user can retry
                app.FilesSelectedTextArea.Enable        = 'on';
                app.FilesSelectedTextArea.Value         = 'Save failed. Please try again.';
                app.SaveoutputfilesButton.Enable        = 'on';
                uialert(app.UIFigure, ME.message, 'Save Error', 'Icon', 'error');
            end

        end

        % Button pushed function: BacktoFileselectionButton
        function BacktoFileselectionButtonPushed(app, event)
            answer = uiconfirm(app.UIFigure, ...
                'This will reset the app and clear all loaded data. Continue?', ...
                'Reset App', ...
                'Options',      {'Reset', 'Cancel'}, ...
                'DefaultOption', 'Cancel', ...
                'CancelOption',  'Cancel', ...
                'Icon',          'warning');

            if ~strcmp(answer, 'Reset')
                return;
            end

            % Clear app data
            app.FilesSelectedTextArea.Value = ''; % Selected pod files cleared
            app.FilesSelectedTextArea_2.Value = ''; % selected metadata cleared
            app.MetaData   = [];
            app.Detections = [];
            app.processList = {};

            % Reset buttons to their initial states
            app.SaveoutputfilesButton.Enable = 'off';
            app.BacktoFileselectionButton.Visible = 'off';
            app.BacktoFileselectionButton.Enable = 'off';
            app.SelectCFPODfilesButton.Enable = 'on';

            % Switch to the file selction tab tab
            app.TabGroup.SelectedTab = app.FileselectionTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileselectionTab = uitab(app.TabGroup);
            app.FileselectionTab.Title = 'File selection';
            app.GridLayout = uigridlayout(app.FileselectionTab);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {''; 'VALKYRIE'; ''; 'Input needed: Porpoise detection files (.CP1 and .CP3 or .FP1 and .FP3) and the corresponding metadata. Please refer to ETN for the correct format for the metadata input (https://www.lifewatch.be/etn/underwater_dataimport). '; 'When selecting detection files choose either single files (but always a complete file pair, e.g. both the CP1 and CP3 files) or choose a folder with project files (can be folder with subfolders).'; ''; 'Upload is only possible per project, and a maximum of 10 deployments can be processed at once.'; ''; 'If you run into any issues, a log file is automatically saved and can be shared for troubleshooting.'};
            app.SelectCFPODfilesButton = uibutton(app.GridLayout, 'push');
            app.SelectCFPODfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCFPODfilesButtonPushed, true);
            app.SelectCFPODfilesButton.Tooltip = {'Click here to select either a folder or specific files to upload.'};
            app.SelectCFPODfilesButton.Text = 'Select C-/F-POD files';
            app.FilesSelectedTextArea = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea.Editable = 'off';
            app.ConfirmselectionButton = uibutton(app.GridLayout, 'push');
            app.ConfirmselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmselectionButtonPushed, true);
            app.ConfirmselectionButton.Enable = 'off';
            app.ConfirmselectionButton.Tooltip = {'Confirm selection of files.'};
            app.ConfirmselectionButton.Text = 'Confirm selection';
            app.SelectmetadatafileButton = uibutton(app.GridLayout, 'push');
            app.SelectmetadatafileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmetadatafileButtonPushed, true);
            app.SelectmetadatafileButton.Enable = 'off';
            app.SelectmetadatafileButton.Tooltip = {'Click to select metadata .csv file to upload.'};
            app.SelectmetadatafileButton.Text = 'Select metadata file';
            app.FilesSelectedTextArea_2 = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea_2.Editable = 'off';
            app.VersionLabel = uilabel(app.GridLayout);
            app.VersionLabel.Text = 'VersionLabel';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.GridLayout2 = uigridlayout(app.MetadataTab);
            app.ChangefilesButton = uibutton(app.GridLayout2, 'push');
            app.ChangefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangefilesButtonPushed, true);
            app.ChangefilesButton.Enable = 'off';
            app.ChangefilesButton.Text = 'Change files';
            app.ProcessfilenamemetadatamatchButton = uibutton(app.GridLayout2, 'push');
            app.ProcessfilenamemetadatamatchButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessfilenamemetadatamatchButtonPushed, true);
            app.ProcessfilenamemetadatamatchButton.Enable = 'off';
            app.ProcessfilenamemetadatamatchButton.Text = 'Process filename metadata match';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.GridLayout3 = uigridlayout(app.OutputTab);
            app.SaveoutputfilesButton = uibutton(app.GridLayout3, 'push');
            app.SaveoutputfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoutputfilesButtonPushed, true);
            app.SaveoutputfilesButton.Enable = 'off';
            app.SaveoutputfilesButton.Text = 'Save output files ';
            app.ProcessingMessage = uitextarea(app.GridLayout3);
            app.ProcessingMessage.Editable = 'off';
            app.BacktoFileselectionButton = uibutton(app.GridLayout3, 'push');
            app.BacktoFileselectionButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoFileselectionButtonPushed, true);
            app.BacktoFileselectionButton.Enable = 'off';
            app.BacktoFileselectionButton.Visible = 'off';
            app.BacktoFileselectionButton.Text = 'Back to File selection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VALKYRIE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end